classdef dockTracking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        btnClose           matlab.ui.control.Image
        DocumentGrid       matlab.ui.container.GridLayout
        ErrorLabel         matlab.ui.control.Label
        LOGButton          matlab.ui.control.Button
        Panel              matlab.ui.container.Panel
        GridLayout2        matlab.ui.container.GridLayout
        Polarization       matlab.ui.control.Label
        PolarizationGauge  matlab.ui.control.Gauge
        PolarizationLabel  matlab.ui.control.Label
        Elevation          matlab.ui.control.Label
        ElevationGrid      matlab.ui.container.GridLayout
        ElevationGauge     matlab.ui.control.NinetyDegreeGauge
        ElevationLabel     matlab.ui.control.Label
        Azimuth            matlab.ui.control.Label
        AzimuthGauge       matlab.ui.control.Gauge
        AzimuthLabel       matlab.ui.control.Label
        PositionGrid       matlab.ui.container.GridLayout
        finalPosValue      matlab.ui.control.Label
        finalPosLabel      matlab.ui.control.Label
        actualPosValue     matlab.ui.control.Label
        actualPosLabel     matlab.ui.control.Label
        initialPosValue    matlab.ui.control.Label
        initialPosLabel    matlab.ui.control.Label
        antennaNameLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Role = 'secondaryDockApp'
    end


    properties (Access = public)
        %-----------------------------------------------------------------%
        Container
        isDocked = true        
        mainApp
        callingApp
    end


    properties (Access = private)
        %-----------------------------------------------------------------%
        EMSatObj
        antennaName
        LOG = struct('type', {}, 'time', {}, 'msg',  {})
        UpdateStatus = true
    end
    

    methods (Access = private)
        %-----------------------------------------------------------------%
        function screenStartup(app, antennaPos, targetPos)
            app.antennaNameLabel.Text = targetPos.Name;
            app.initialPosValue.Text  = sprintf('(%.3fº, %.3fº, %.3fº)', antennaPos.Azimuth, antennaPos.Elevation, antennaPos.Polarization);
            app.finalPosValue.Text    = sprintf('(%.3fº, %.3fº, %.3fº)', targetPos.Azimuth,  targetPos.Elevation,  targetPos.Polarization);
            screenUpdate(app, antennaPos)

            app.AzimuthGauge.Value = antennaPos.Azimuth;
            if targetPos.Azimuth ~= antennaPos.Azimuth
                [minAz, maxAz] = bounds([antennaPos.Azimuth, targetPos.Azimuth]);
                set(app.AzimuthGauge, 'ScaleColors', {[0 1 0] [1 1 0] [0 1 0]}, ...
                                      'ScaleColorLimits', [0 minAz; minAz maxAz; maxAz 360])
            end

            app.ElevationGauge.Value = antennaPos.Elevation;
            if targetPos.Elevation ~= antennaPos.Elevation
                set(app.ElevationGauge, 'ScaleColors', [1 1 0], ...
                                        'ScaleColorLimits', sort([antennaPos.Elevation, targetPos.Elevation]))
            end

            app.PolarizationGauge.Value = antennaPos.Polarization;
            if targetPos.Polarization ~= antennaPos.Polarization
                [minPol, maxPol] = bounds([antennaPos.Polarization, targetPos.Polarization]);
                set(app.PolarizationGauge, 'ScaleColors', {[0 1 0] [1 1 0] [0 1 0]}, ...
                                           'ScaleColorLimits', [0 minPol; minPol maxPol; maxPol 360])
            end

            drawnow
        end

        %-----------------------------------------------------------------%
        function screenUpdate(app, antennaPos)
            app.actualPosValue.Text  = sprintf('(%.3fº, %.3fº, %.3fº)', antennaPos.Azimuth, antennaPos.Elevation, antennaPos.Polarization);

            app.Azimuth.Text      = sprintf('%.3fº', antennaPos.Azimuth);
            app.Elevation.Text    = sprintf('%.3fº', antennaPos.Elevation);
            app.Polarization.Text = sprintf('%.3fº', antennaPos.Polarization);
        end

        %-----------------------------------------------------------------%
        function setNewPosition(app, antennaPos, targetPos)
            errorTol = class.Constants.errorPosTolerance;

            if abs(targetPos.Azimuth      - antennaPos.Azimuth)      >= errorTol || ...
               abs(targetPos.Elevation    - antennaPos.Elevation)    >= errorTol || ...
               abs(targetPos.Polarization - antennaPos.Polarization) >= errorTol
                msgError = app.EMSatObj.AntennaPositionSET(targetPos);

                if ~isempty(msgError)
                    error(msgError)
                    closeFcn(app)
                end
            end
        end

        %-----------------------------------------------------------------%
        function updatePosition(app, antennaPos, targetPos)
            errorTol = class.Constants.errorPosTolerance;

            % Posição de referência
            refPos   = struct('Azimuth', [], 'Elevation', [], 'Polarization', []);
            refPos   = updateReference(app, refPos, antennaPos);

            trackTic = tic;
            while app.UpdateStatus
                if toc(trackTic) > 10
                    if isequal(refPos, antennaPos)
                        ui.Dialog(app.UIFigure, 'warning', 'Não alterado o apontamento da antena mesmo após decorridos 10 segundos...');
                    else
                        refPos = updateReference(app, refPos, antennaPos);
                    end
                    trackTic = tic;
                end

                [newAntennaPos, errorMsg] = app.EMSatObj.AntennaPositionGET(targetPos.Name);
                Timestamp = datestr(now, 'dd/mm/yyyy HH:MM:ss');

                if isempty(errorMsg)
                    antennaPos = newAntennaPos;
    
                    app.AzimuthGauge.Value      = antennaPos.Azimuth;
                    app.ElevationGauge.Value    = antennaPos.Elevation;
                    app.PolarizationGauge.Value = antennaPos.Polarization;
                    screenUpdate(app, antennaPos)
    
                    set(app.ErrorLabel, FontColor = 'black', ...
                                        Text = sprintf('%s - Coleta da posição atual do conjunto antena/LNB realizada com sucesso', Timestamp))
    
                    if abs(targetPos.Azimuth      - antennaPos.Azimuth)      < errorTol && ...
                       abs(targetPos.Elevation    - antennaPos.Elevation)    < errorTol && ...
                       abs(targetPos.Polarization - antennaPos.Polarization) < errorTol
                        break
                    end

                else
                    app.LOG(end+1) = struct('type', 'error', 'time', Timestamp, 'msg', errorMsg);
                    set(app.ErrorLabel, FontColor = 'red', ...
                                        Text = sprintf('%s - Registro de erro na coleta da posição atual do conjunto antena/LNB', Timestamp))
                end
                drawnow
            end

            closeFcn(app)
        end

        %-----------------------------------------------------------------%
        function refAntennaPos = updateReference(app, refAntennaPos, antennaPos)
            refAntennaPos.Azimuth      = antennaPos.Azimuth;
            refAntennaPos.Elevation    = antennaPos.Elevation;
            refAntennaPos.Polarization = antennaPos.Polarization;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, callingApp, antennaPos, targetPos)
            
            try
                appEngine.boot(app, app.Role, mainApp, [])

                app.EMSatObj = mainApp.EMSatObj;
                screenStartup(app,  antennaPos, targetPos)
                setNewPosition(app, antennaPos, targetPos)
                updatePosition(app, antennaPos, targetPos)
                
            catch ME
                ui.Dialog(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end

        end

        % Callback function: UIFigure, btnClose
        function closeFcn(app, event)
            
            ipcMainMatlabCallsHandler(app.mainApp, app, 'closeFcnCallFromPopupApp')
            delete(app)
            
        end

        % Button pushed function: LOGButton
        function LOGButtonPushed(app, event)
            
            if ~isempty(app.LOG)
                logTable = struct2table(app.LOG);
                logMsg   = strjoin("<b>" + logTable.time + " - " + upper(logTable.type) + "</b>" + newline + logTable.msg, '\n\n');
            else
                logMsg = 'Não há registro de erro.';
            end

            ui.Dialog(app.UIFigure, 'warning', logMsg);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta';
            app.UIFigure.Icon = 'icon_32.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.DocumentGrid = uigridlayout(app.GridLayout);
            app.antennaNameLabel = uilabel(app.DocumentGrid);
            app.antennaNameLabel.Text = 'MCL-1';
            app.PositionGrid = uigridlayout(app.DocumentGrid);
            app.initialPosLabel = uilabel(app.PositionGrid);
            app.initialPosLabel.Text = 'Posição inicial:';
            app.initialPosValue = uilabel(app.PositionGrid);
            app.initialPosValue.Text = '(0.000º, 0.000º, 0.000º)';
            app.actualPosLabel = uilabel(app.PositionGrid);
            app.actualPosLabel.Text = 'Posição atual:';
            app.actualPosValue = uilabel(app.PositionGrid);
            app.actualPosValue.Text = '(0.000º, 0.000º, 0.000º)';
            app.finalPosLabel = uilabel(app.PositionGrid);
            app.finalPosLabel.Text = 'Posição alvo:';
            app.finalPosValue = uilabel(app.PositionGrid);
            app.finalPosValue.Text = '(0.000º, 0.000º, 0.000º)';
            app.Panel = uipanel(app.DocumentGrid);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.AzimuthLabel = uilabel(app.GridLayout2);
            app.AzimuthLabel.Text = 'AZIMUTE';
            app.AzimuthGauge = uigauge(app.GridLayout2, 'circular');
            app.AzimuthGauge.Limits = [0 360];
            app.AzimuthGauge.MajorTicks = [0 45 90 135 180 225 270 315 360];
            app.AzimuthGauge.MajorTickLabels = {'0', '45', '90', '135', '180', '225', '270', '315', '360'};
            app.Azimuth = uilabel(app.GridLayout2);
            app.Azimuth.Text = '0.000º';
            app.ElevationLabel = uilabel(app.GridLayout2);
            app.ElevationLabel.Text = 'ELEVAÇÃO';
            app.ElevationGrid = uigridlayout(app.GridLayout2);
            app.ElevationGauge = uigauge(app.ElevationGrid, 'ninetydegree');
            app.ElevationGauge.Limits = [0 90];
            app.ElevationGauge.MajorTicks = [0 22.5 45 67.5 90];
            app.ElevationGauge.MajorTickLabels = {'0', '22.5', '45', '67.5', '90'};
            app.ElevationGauge.MinorTicks = [0 3 6 9 12 15 18 27 30 33 36 39 42 45 48 51 54 57 60 63 72 75 78 81 84 87 90];
            app.Elevation = uilabel(app.GridLayout2);
            app.Elevation.Text = '0.000º';
            app.PolarizationLabel = uilabel(app.GridLayout2);
            app.PolarizationLabel.Text = 'POLARIZAÇÃO';
            app.PolarizationGauge = uigauge(app.GridLayout2, 'circular');
            app.PolarizationGauge.Limits = [0 360];
            app.PolarizationGauge.MajorTicks = [0 45 90 135 180 225 270 315 360];
            app.PolarizationGauge.MajorTickLabels = {'0', '45', '90', '135', '180', '225', '270', '315', '360'};
            app.Polarization = uilabel(app.GridLayout2);
            app.Polarization.Text = '0.000º';
            app.LOGButton = uibutton(app.DocumentGrid, 'push');
            app.LOGButton.ButtonPushedFcn = createCallbackFcn(app, @LOGButtonPushed, true);
            app.LOGButton.Icon = 'LOG_32.png';
            app.LOGButton.Tooltip = {'Apresenta LOG do processo de apontamento da antena'};
            app.LOGButton.Text = '';
            app.ErrorLabel = uilabel(app.DocumentGrid);
            app.ErrorLabel.Text = '';
            app.btnClose = uiimage(app.GridLayout);
            app.btnClose.ImageClickedFcn = createCallbackFcn(app, @closeFcn, true);
            app.btnClose.Tag = 'Close';
            app.btnClose.ImageSource = 'Delete_12SVG.svg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dockTracking(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end