classdef winConfig < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        DockModule                     matlab.ui.container.GridLayout
        DockCloseButton                matlab.ui.control.Image
        DockUndockButton               matlab.ui.control.Image
        SubTabGroup                    matlab.ui.container.TabGroup
        SubTab1                        matlab.ui.container.Tab
        SubGrid1                       matlab.ui.container.GridLayout
        openAuxiliarApp2Debug          matlab.ui.control.CheckBox
        openAuxiliarAppAsDocked        matlab.ui.control.CheckBox
        versionInfoRefresh             matlab.ui.control.Image
        versionInfo                    matlab.ui.control.Label
        versionInfoLabel               matlab.ui.control.Label
        SubTab2                        matlab.ui.container.Tab
        SubGrid2                       matlab.ui.container.GridLayout
        general_versionPanel           matlab.ui.container.Panel
        server_Grid                    matlab.ui.container.GridLayout
        server_Port                    matlab.ui.control.NumericEditField
        server_PortLabel               matlab.ui.control.Label
        server_IP                      matlab.ui.control.EditField
        server_IPLabel                 matlab.ui.control.Label
        server_ClientList              matlab.ui.control.EditField
        server_ClientListLabel         matlab.ui.control.Label
        server_Key                     matlab.ui.control.EditField
        server_KeyLabel                matlab.ui.control.Label
        server_Status                  matlab.ui.control.DropDown
        server_StatusLabel             matlab.ui.control.Label
        general_versionLock            matlab.ui.control.Image
        general_versionLabel           matlab.ui.control.Label
        general_FilePanel              matlab.ui.container.Panel
        general_stationGrid            matlab.ui.container.GridLayout
        general_lastSessionInfo        matlab.ui.control.CheckBox
        general_stationLongitude       matlab.ui.control.NumericEditField
        general_stationLongitudeLabel  matlab.ui.control.Label
        general_stationLatitude        matlab.ui.control.NumericEditField
        general_stationLatitudeLabel   matlab.ui.control.Label
        general_stationType            matlab.ui.control.DropDown
        general_stationTypeLabel       matlab.ui.control.Label
        general_stationName            matlab.ui.control.EditField
        general_stationNameLabel       matlab.ui.control.Label
        general_FileLock               matlab.ui.control.Image
        general_FileLabel              matlab.ui.control.Label
        SubTab3                        matlab.ui.container.Tab
        SubGrid3                       matlab.ui.container.GridLayout
        plot_WaterfallLabel            matlab.ui.control.Label
        configPlotRefresh              matlab.ui.control.Image
        plot_IntegrationPanel          matlab.ui.container.Panel
        plot_IntegrationGrid           matlab.ui.container.GridLayout
        plot_IntegrationTime           matlab.ui.control.NumericEditField
        plot_IntegrationTimeLabel      matlab.ui.control.Label
        plot_IntegrationTrace          matlab.ui.control.NumericEditField
        plot_IntegrationTraceLabel     matlab.ui.control.Label
        plot_IntegrationLabel          matlab.ui.control.Label
        plot_WaterfallPanel            matlab.ui.container.Panel
        plot_WaterfallGrid             matlab.ui.container.GridLayout
        plot_WaterfallDepth            matlab.ui.control.DropDown
        plot_WaterfallDepthLabel       matlab.ui.control.Label
        plot_WaterfallColormap         matlab.ui.control.DropDown
        plot_WaterfallColormapLabel    matlab.ui.control.Label
        plot_colorsPanel               matlab.ui.container.Panel
        plot_colorsGrid                matlab.ui.container.GridLayout
        plot_colorsClearWrite          matlab.ui.control.ColorPicker
        plot_colorsClearWriteLabel     matlab.ui.control.Label
        plot_colorsMaxHold             matlab.ui.control.ColorPicker
        plot_colorsMaxHoldLabel        matlab.ui.control.Label
        plot_colorsAverage             matlab.ui.control.ColorPicker
        plot_colorsAverageLabel        matlab.ui.control.Label
        plot_colorsMinHold             matlab.ui.control.ColorPicker
        plot_colorsMinHoldLabel        matlab.ui.control.Label
        plot_colorsLabel               matlab.ui.control.Label
        plot_TiledSpacing              matlab.ui.control.DropDown
        plot_TiledSpacingLabel         matlab.ui.control.Label
        SubTab4                        matlab.ui.container.Tab
        SubGrid4                       matlab.ui.container.GridLayout
        userPathButton                 matlab.ui.control.Image
        userPath                       matlab.ui.control.EditField
        userPathLabel                  matlab.ui.control.Label
        DataHubPOSTButton              matlab.ui.control.Image
        DataHubPOST                    matlab.ui.control.EditField
        DATAHUBPOSTLabel               matlab.ui.control.Label
        Toolbar                        matlab.ui.container.GridLayout
        tool_openDevTools              matlab.ui.control.Image
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Role = 'secondaryApp'
        Context = 'CONFIG'
    end


    properties (Access = public)
        %-----------------------------------------------------------------%
        Container
        isDocked = false
        mainApp
        jsBackDoor
        progressDialog
    end


    properties (Access = private)
        %-----------------------------------------------------------------%
        defaultValues
    end


    methods (Access = public)
        %-----------------------------------------------------------------%
        function ipcSecondaryJSEventsHandler(app, event)
            try
                switch event.HTMLEventName
                    case 'renderer'
                        appEngine.activate(app, app.Role)

                    otherwise
                        ipcMainJSEventsHandler(app.mainApp, event)
                end

            catch ME
                ui.Dialog(app.UIFigure, 'error', ME.message);
            end
        end

        %-----------------------------------------------------------------%
        function applyJSCustomizations(app, tabIndex)
            if app.SubTabGroup.UserData.isTabInitialized(tabIndex)
                return
            end
            app.SubTabGroup.UserData.isTabInitialized(tabIndex) = true;
            
            switch tabIndex
                case 1
                    appName = class(app);
                    elToModify = {
                        app.versionInfo;
                        app.versionInfoRefresh;
                        app.tool_openDevTools;
                        app.DockUndockButton;
                        app.DockCloseButton
                    };
                    ui.CustomizationBase.getElementsDataTag(elToModify);

                    try
                        ui.TextView.startup(app.jsBackDoor, app.versionInfo, appName);
                    catch
                    end

                    try
                        sendEventToHTMLSource(app.jsBackDoor, 'initializeComponents', { ...
                            struct('appName', appName, 'dataTag', app.versionInfoRefresh.UserData.id,  'tooltip', struct('defaultPosition', 'top', 'textContent', 'Verifica atualizações')), ...
                            struct('appName', appName, 'dataTag', app.tool_openDevTools.UserData.id,   'tooltip', struct('defaultPosition', 'top', 'textContent', 'Abre DevTools')), ...
                            struct('appName', appName, 'dataTag', app.DockUndockButton.UserData.id, 'tooltip', struct('defaultPosition', 'bottom', 'textContent', 'Reabre módulo em outra janela')), ...
                            struct('appName', appName, 'dataTag', app.DockCloseButton.UserData.id, 'tooltip', struct('defaultPosition', 'bottom', 'textContent', 'Fecha módulo')) ...
                        });
                    catch
                    end

                case 2
                    updatePanel_ERMx(app)

                case 3
                    appName = class(app);
                    elToModify = {
                        app.configPlotRefresh
                    };
                    ui.CustomizationBase.getElementsDataTag(elToModify);

                    try
                        sendEventToHTMLSource(app.jsBackDoor, 'initializeComponents', { ...
                            struct('appName', appName, 'dataTag', app.configPlotRefresh.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Retorna às configurações iniciais')) ...
                        });
                    catch
                    end

                    updatePanel_Plot(app)

                case 4
                    if ~strcmp(app.mainApp.executionMode, 'webApp')
                        set([app.DataHubPOSTButton, app.userPathButton], 'Enable', 1)
                    end
                    updatePanel_Folder(app)
            end
        end

        %-----------------------------------------------------------------%
        function initializeAppProperties(app)
            % Lê a versão de "GeneralSettings.json" que vem junto ao
            % projeto (e não a versão armazenada em "ProgramData").
            projectFolder     = appEngine.util.Path(class.Constants.appName, app.mainApp.rootFolder);
            projectFilePath   = fullfile(projectFolder, 'GeneralSettings.json');
            projectGeneral    = jsondecode(fileread(projectFilePath));

            app.defaultValues = struct('plot',        projectGeneral.plot, ...
                                       'integration', projectGeneral.context.TASK_VIEW.integration);

            app.general_FileLock.UserData    = struct('status', false);
            app.general_versionLock.UserData = struct('status', false);
        end

        %-----------------------------------------------------------------%
        function initializeUIComponents(app)
            if ~strcmp(app.mainApp.executionMode, 'webApp')
                app.DockUndockButton.Enable       = 1;
                app.tool_openDevTools.Enable       = 1;
                app.versionInfoRefresh.Enable = 1;
                app.openAuxiliarAppAsDocked.Enable = 1;
            end

            if ~isdeployed
                app.openAuxiliarApp2Debug.Enable   = 1;
            end
        end

        %-----------------------------------------------------------------%
        function applyInitialLayout(app)
            % Versão
            ui.TextView.update(app.versionInfo, util.HtmlTextGenerator.AppInfo(app.mainApp.General, app.mainApp.rootFolder, app.mainApp.executionMode, app.mainApp.renderCount, "textview"));

            % Modo de operação
            app.openAuxiliarAppAsDocked.Value = app.mainApp.General.operationMode.Dock;
            app.openAuxiliarApp2Debug.Value   = app.mainApp.General.operationMode.Debug;
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function updatePanel_ERMx(app)
            % ERMx
            app.general_stationName.Value       = app.mainApp.General.context.CONFIG.station.name;
            app.general_stationType.Items       = {app.mainApp.General.context.CONFIG.station.type};
            app.general_stationLatitude.Value   = app.mainApp.General.context.CONFIG.station.latitude;
            app.general_stationLongitude.Value  = app.mainApp.General.context.CONFIG.station.longitude;
            app.general_lastSessionInfo.Value   = app.mainApp.General.context.CONFIG.restoreLastSession;

            % WEBSERVICE
            switch app.mainApp.General.context.SERVER.status
                case 1; app.server_Status.Value = 'ON';
                case 0; app.server_Status.Value = 'OFF';
            end

            app.server_Key.Value                = app.mainApp.General.context.SERVER.key;
            app.server_ClientList.Value         = strjoin(app.mainApp.General.context.SERVER.clientList, ', ');
            app.server_IP.Value                 = app.mainApp.General.context.SERVER.ip;
            app.server_Port.Value               = app.mainApp.General.context.SERVER.port;
        end

        %-----------------------------------------------------------------%
        function updatePanel_Plot(app)
            app.plot_TiledSpacing.Value      = app.mainApp.UIAxes1.Parent.TileSpacing;

            app.plot_colorsMinHold.Value     = app.mainApp.General.plot.minHold.Color;
            app.plot_colorsAverage.Value     = app.mainApp.General.plot.average.Color;
            app.plot_colorsMaxHold.Value     = app.mainApp.General.plot.maxHold.Color;
            app.plot_colorsClearWrite.Value  = app.mainApp.General.plot.clearWrite.Color;
            
            app.plot_WaterfallColormap.Items = unique([app.plot_WaterfallColormap.Items, {app.mainApp.General.plot.waterfall.Colormap}]);
            app.plot_WaterfallColormap.Value = app.mainApp.General.plot.waterfall.Colormap;

            app.plot_WaterfallDepth.Items    = unique([app.plot_WaterfallDepth.Items, {num2str(app.mainApp.General.plot.waterfall.Depth)}], 'stable');
            app.plot_WaterfallDepth.Value    = {num2str(app.mainApp.General.plot.waterfall.Depth)};

            app.plot_IntegrationTrace.Value  = app.mainApp.General.context.TASK_VIEW.integration.traceMode;
            app.plot_IntegrationTime.Value   = app.mainApp.General.context.TASK_VIEW.integration.sampleTimeSeconds;

            if checkEdition(app, 'PLOT')
                app.configPlotRefresh.Visible = 1;
            else
                app.configPlotRefresh.Visible = 0;
            end
        end

        %-----------------------------------------------------------------%
        function updatePanel_Folder(app)
            DataHub_POST = app.mainApp.General.fileFolder.DataHub_POST;    
            if isfolder(DataHub_POST)
                app.DataHubPOST.Value = DataHub_POST;
            end

            app.userPath.Value = app.mainApp.General.fileFolder.userPath;                
        end

        %-----------------------------------------------------------------%
        function editionFlag = checkEdition(app, tabName)
            editionFlag   = false;
            currentValues = struct('plot',        app.mainApp.General.plot, ...
                                   'integration', app.mainApp.General.context.TASK_VIEW.integration);

            switch tabName
                case 'ERMx'
                    % ...
                case 'PLOT'
                    if ~isequal(currentValues, app.defaultValues)
                        editionFlag = true;
                    end
            end
        end

        %-----------------------------------------------------------------%
        function status = IPv4Validation(app, ipAddress)
            status   = true;
            ipString = regexp(ipAddress, '\d*[.]{1}\d{1,3}[.]{1}\d{1,3}[.]{1}\d*', 'match');
            
            if isempty(ipString)
                status = false;
            else
                ipArray = cellfun(@(x) str2double(x), strsplit(char(ipString), '.'));
                if any(ipArray > 255) || any(isnan(ipArray))
                    status = false;
                end                    
            end
        end

        %-----------------------------------------------------------------%
        function saveGeneralSettings(app)
            appEngine.util.generalSettingsSave(class.Constants.appName, app.mainApp.rootFolder, app.mainApp.General_I, app.mainApp.executionMode)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            
            try
                appEngine.boot(app, app.Role, mainApp)
            catch ME
                ui.Dialog(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            ipcMainMatlabCallsHandler(app.mainApp, app, 'closeFcn', app.Context)
            delete(app)
            
        end

        % Image clicked function: DockCloseButton, DockUndockButton
        function onDockModuleGroupButtonClicked(app, event)
            
            [idx, auxAppTag, relatedButton] = getAppInfoFromHandle(app.mainApp.tabGroupController, app);

            switch event.Source
                case app.DockUndockButton
                    appGeneral = app.mainApp.General;
                    appGeneral.operationMode.Dock = false;

                    inputArguments = ipcMainMatlabCallsHandler(app.mainApp, app, 'dockButtonPushed', auxAppTag);
                    app.mainApp.tabGroupController.Components.appHandle{idx} = [];
                    
                    openModule(app.mainApp.tabGroupController, relatedButton, false, appGeneral, inputArguments{:})
                    closeModule(app.mainApp.tabGroupController, auxAppTag, app.mainApp.General, 'undock')
                    
                    delete(app)

                case app.DockCloseButton
                    closeModule(app.mainApp.tabGroupController, auxAppTag, app.mainApp.General)
            end

        end

        % Selection change function: SubTabGroup
        function SubTabGroup_TabSelectionChanged(app, event)
            
            [~, tabIndex] = ismember(app.SubTabGroup.SelectedTab, app.SubTabGroup.Children);
            applyJSCustomizations(app, tabIndex)

        end

        % Image clicked function: versionInfoRefresh
        function Toolbar_AppEnvRefreshButtonPushed(app, event)
            
            app.progressDialog.Visible = 'visible';

            htmlContent = util.HtmlTextGenerator.checkUpdate(app.mainApp.General, app.mainApp.rootFolder);
            ui.Dialog(app.UIFigure, "info", htmlContent);

            app.progressDialog.Visible = 'hidden';

        end

        % Image clicked function: tool_openDevTools
        function Toolbar_OpenDevToolsClicked(app, event)
            
            ipcMainMatlabCallsHandler(app.mainApp, app, 'openDevTools')

        end

        % Value changed function: openAuxiliarApp2Debug, 
        % ...and 1 other component
        function Config_GeneralParameterValueChanged(app, event)
            
            switch event.Source
                case app.openAuxiliarAppAsDocked
                    app.mainApp.General.operationMode.Dock  = app.openAuxiliarAppAsDocked.Value;

                case app.openAuxiliarApp2Debug
                    app.mainApp.General.operationMode.Debug = app.openAuxiliarApp2Debug.Value;
            end

            app.mainApp.General_I.operationMode = app.mainApp.General.operationMode;
            saveGeneralSettings(app)

        end

        % Image clicked function: DataHubPOSTButton, userPathButton
        function Config_FolderButtonPushed(app, event)
            
            try
                relatedFolder = eval(sprintf('app.%s.Value', event.Source.Tag));
            catch
                relatedFolder = app.mainApp.General.fileFolder.(event.Source.Tag);
            end
            
            if isfolder(relatedFolder)
                initialFolder = relatedFolder;
            elseif isfile(relatedFolder)
                initialFolder = fileparts(relatedFolder);
            else
                initialFolder = app.userPath.Value;
            end

            selectedFolder = uigetdir(initialFolder);
            if ~strcmp(app.mainApp.executionMode, 'webApp')
                figure(app.UIFigure)
            end

            if selectedFolder
                switch event.Source
                    case app.DataHubPOSTButton
                        if strcmp(app.mainApp.General.fileFolder.DataHub_POST, selectedFolder) 
                            return
                        else
                            selectedFolderFiles = dir(selectedFolder);
                            if ~ismember('.appcoleta_post', {selectedFolderFiles.name})
                                ui.Dialog(app.UIFigure, 'error', 'Não se trata da pasta "DataHub - POST", do appColeta.');
                                return
                            end

                            app.DataHubPOST.Value = selectedFolder;
                            app.mainApp.General.fileFolder.DataHub_POST = selectedFolder;
    
                            ipcMainMatlabCallsHandler(app.mainApp, app, 'checkDataHubLampStatus')
                        end

                    case app.userPathButton
                        app.userPath.Value = selectedFolder;
                        app.mainApp.General.fileFolder.userPath = selectedFolder;
                end

                app.mainApp.General_I.fileFolder = app.mainApp.General.fileFolder;

                updatePanel_Folder(app)
                saveGeneralSettings(app)
            end

        end

        % Value changed function: general_lastSessionInfo, 
        % ...and 9 other components
        function general_ParameterChanged(app, event)
            
            closeAddTaskModuleFlag = false;

            switch event.Source
                case app.general_stationName
                    if isempty(regexp(app.general_stationName.Value, '^EMSat$|^UMS.*|^ERMx-[A-Z]{2}-[0-9][1-9]$', 'once'))
                        msgQuestion   = ['O nome esperado de uma estação é <b>"ERMx-UF-XX"</b>, sendo "UF" a sigla da unidade da '     ...
                                         'federação e XX dois dígitos numéricos (01 a 99). Além disso, são previstas inclusões '       ...
                                         'de nomes de estações iniciando com <b>"UMS</b>" ou <b>"EMSat"</b>.<br><br>O nome inserido, ' ...
                                         'contudo, difere dessas opções. Deseja continuar?'];
                        userSelection = ui.Dialog(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 2, 2);
                            if strcmp(userSelection, 'Não')
                                app.general_stationName.Value = event.PreviousValue;
                                return
                            end
                    end

                    app.mainApp.General.context.CONFIG.station.name = app.general_stationName.Value;

                case app.general_stationType
                    app.mainApp.General.context.CONFIG.station.type = app.general_stationType.Value;

                case app.general_stationLatitude
                    closeAddTaskModuleFlag = true;
                    app.mainApp.General.context.CONFIG.station.latitude = app.general_stationLatitude.Value;

                case app.general_stationLongitude
                    closeAddTaskModuleFlag = true;
                    app.mainApp.General.context.CONFIG.station.longitude = app.general_stationLongitude.Value;

                case app.general_lastSessionInfo
                    app.mainApp.General.context.CONFIG.restoreLastSession = app.general_lastSessionInfo.Value;

                case app.server_Status
                    switch app.server_Status.Value
                        case 'ON';  app.mainApp.General.context.SERVER.status = 1;
                        case 'OFF'; app.mainApp.General.context.SERVER.status = 0;
                    end

                case app.server_Key
                    app.server_Key.Value = replace(app.server_Key.Value, ' ', '');
                    app.mainApp.General.context.SERVER.key = app.server_Key.Value;

                case app.server_ClientList
                    app.server_ClientList.Value = replace(app.server_ClientList.Value, ' ', '');
                    
                    if isempty(app.server_ClientList.Value)
                        app.mainApp.General.context.SERVER.clientList = {};
                    else
                        app.mainApp.General.context.SERVER.clientList = strsplit(app.server_ClientList.Value, ',');
                    end

                    app.server_ClientList.Value = strjoin(app.mainApp.General.context.SERVER.clientList, ', ');

                case app.server_IP
                    app.server_IP.Value = strtrim(app.server_IP.Value);

                    if IPv4Validation(app, app.server_IP.Value) || isempty(app.server_IP.Value)
                        app.mainApp.General.context.SERVER.ip = app.server_IP.Value;
                    else
                        app.server_IP.Value = event.PreviousValue;
                        ui.Dialog(app.UIFigure, 'warning', 'Endereço inválido (IPv4).');
                    end

                case app.server_Port
                    app.mainApp.General.context.SERVER.port = app.server_Port.Value;
            end

            if closeAddTaskModuleFlag
                ipcMainMatlabCallsHandler(app.mainApp, app, 'closeFcn', 'TASK_ADD')
            end
            
            app.mainApp.General_I.context.CONFIG = app.mainApp.General.context.CONFIG;
            app.mainApp.General_I.context.SERVER = app.mainApp.General.context.SERVER;
            saveGeneralSettings(app)

        end

        % Image clicked function: general_FileLock, general_versionLock
        function general_PanelLockControl(app, event)
            
            switch event.Source
                case app.general_FileLock
                    gridContainer = app.general_stationGrid;
                case app.general_versionLock
                    gridContainer = app.server_Grid;
            end

            event.Source.UserData.status = ~event.Source.UserData.status;
            if event.Source.UserData.status
                event.Source.ImageSource = 'lockOpen_32.png';
                set(findobj(gridContainer.Children, '-not', 'Type', 'uilabel'), 'Enable', 1)
            else
                event.Source.ImageSource = 'lockClose_32.png';
                set(findobj(gridContainer.Children, '-not', 'Type', 'uilabel'), 'Enable', 0)
            end

        end

        % Value changed function: plot_TiledSpacing
        function plot_TiledSpacingValueChanged(app, event)
            
            ipcMainMatlabCallsHandler(app.mainApp, app, 'onAxesTileSpacingChange', app.plot_TiledSpacing.Value)

        end

        % Callback function: plot_colorsAverage, plot_colorsClearWrite, 
        % ...and 2 other components
        function plot_colorsMinHoldValueChanged(app, event)
            
            initialColor  = event.PreviousValue;
            selectedColor = event.Value;

            if ~isequal(initialColor, selectedColor)
                selectedColor = rgb2hex(selectedColor);
    
                switch event.Source
                    case app.plot_colorsMinHold
                        plotTag = 'MinHold';
                        app.mainApp.General.plot.minHold.Color    = selectedColor;
                    case app.plot_colorsAverage
                        plotTag = 'Average';
                        app.mainApp.General.plot.average.Color    = selectedColor;
                    case app.plot_colorsMaxHold
                        plotTag = 'MaxHold';
                        app.mainApp.General.plot.maxHold.Color    = selectedColor;
                    case app.plot_colorsClearWrite
                        plotTag = 'ClearWrite';
                        app.mainApp.General.plot.clearWrite.Color = selectedColor;
                end

                ipcMainMatlabCallsHandler(app.mainApp, app, 'onPlotColorChange', plotTag)
            end

            app.mainApp.General_I.plot = app.mainApp.General.plot;
            saveGeneralSettings(app)

            updatePanel_Plot(app)

        end

        % Value changed function: plot_IntegrationTime, 
        % ...and 3 other components
        function plot_WaterfallColormapValueChanged(app, event)
            
            switch event.Source
                case app.plot_WaterfallColormap
                    ipcMainMatlabCallsHandler(app.mainApp, app, 'onWaterfallColormapChange', app.plot_WaterfallColormap.Value)
                    app.mainApp.General.plot.waterfall.Colormap = app.plot_WaterfallColormap.Value;

                case app.plot_WaterfallDepth
                    app.mainApp.General.plot.waterfall.Depth = str2double(app.plot_WaterfallDepth.Value);
                
                case app.plot_IntegrationTrace
                    app.mainApp.General.context.TASK_VIEW.integration.traceMode = app.plot_IntegrationTrace.Value;

                case app.plot_IntegrationTime
                    app.mainApp.General.context.TASK_VIEW.integration.sampleTimeSeconds = app.plot_IntegrationTime.Value;
            end

            app.mainApp.General_I.plot = app.mainApp.General.plot;
            app.mainApp.General_I.context.TASK_VIEW = app.mainApp.General.context.TASK_VIEW;
            saveGeneralSettings(app)

            updatePanel_Plot(app)

        end

        % Image clicked function: configPlotRefresh
        function configPlotRefreshImageClicked(app, event)
            
            if ~checkEdition(app, 'PLOT')
                app.configPlotRefresh.Visible = 0;
                return
            
            else                
                app.mainApp.General.plot = app.defaultValues.plot;
                app.mainApp.General.context.TASK_VIEW.integration = app.defaultValues.integration;

                app.mainApp.General_I.plot = app.mainApp.General.plot;
                app.mainApp.General_I.context.TASK_VIEW = app.mainApp.General.context.TASK_VIEW;
                saveGeneralSettings(app)
                
                updatePanel_Plot(app)
                ipcMainMatlabCallsHandler(app.mainApp, app, 'onPlotColorChange', 'ClearWrite')
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta';
            app.UIFigure.Icon = 'icon_32.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Toolbar = uigridlayout(app.GridLayout);
            app.tool_openDevTools = uiimage(app.Toolbar);
            app.tool_openDevTools.ImageClickedFcn = createCallbackFcn(app, @Toolbar_OpenDevToolsClicked, true);
            app.tool_openDevTools.Enable = 'off';
            app.tool_openDevTools.Tooltip = {''};
            app.tool_openDevTools.ImageSource = 'Debug_18.png';
            app.SubTabGroup = uitabgroup(app.GridLayout);
            app.SubTabGroup.SelectionChangedFcn = createCallbackFcn(app, @SubTabGroup_TabSelectionChanged, true);
            app.SubTab1 = uitab(app.SubTabGroup);
            app.SubTab1.Title = 'ASPECTOS GERAIS';
            app.SubGrid1 = uigridlayout(app.SubTab1);
            app.versionInfoLabel = uilabel(app.SubGrid1);
            app.versionInfoLabel.Text = 'AMBIENTE';
            app.versionInfo = uilabel(app.SubGrid1);
            app.versionInfo.Interpreter = 'html';
            app.versionInfo.Text = '';
            app.versionInfoRefresh = uiimage(app.SubGrid1);
            app.versionInfoRefresh.ImageClickedFcn = createCallbackFcn(app, @Toolbar_AppEnvRefreshButtonPushed, true);
            app.versionInfoRefresh.Enable = 'off';
            app.versionInfoRefresh.Tooltip = {''};
            app.versionInfoRefresh.ImageSource = 'Refresh_18.png';
            app.openAuxiliarAppAsDocked = uicheckbox(app.SubGrid1);
            app.openAuxiliarAppAsDocked.ValueChangedFcn = createCallbackFcn(app, @Config_GeneralParameterValueChanged, true);
            app.openAuxiliarAppAsDocked.Enable = 'off';
            app.openAuxiliarAppAsDocked.Text = 'Modo DOCK: módulos auxiliares abertos na janela principal do app';
            app.openAuxiliarApp2Debug = uicheckbox(app.SubGrid1);
            app.openAuxiliarApp2Debug.ValueChangedFcn = createCallbackFcn(app, @Config_GeneralParameterValueChanged, true);
            app.openAuxiliarApp2Debug.Enable = 'off';
            app.openAuxiliarApp2Debug.Text = 'Modo DEBUG';
            app.SubTab2 = uitab(app.SubTabGroup);
            app.SubTab2.Title = 'ERMx';
            app.SubGrid2 = uigridlayout(app.SubTab2);
            app.general_FileLabel = uilabel(app.SubGrid2);
            app.general_FileLabel.Text = 'ESTAÇÃO DE MONITORAÇÃO (aplicável apenas quando se tratar de ERMx, UMS ou EMSat)';
            app.general_FileLock = uiimage(app.SubGrid2);
            app.general_FileLock.ImageClickedFcn = createCallbackFcn(app, @general_PanelLockControl, true);
            app.general_FileLock.ImageSource = 'lockClose_32.png';
            app.general_FilePanel = uipanel(app.SubGrid2);
            app.general_stationGrid = uigridlayout(app.general_FilePanel);
            app.general_stationNameLabel = uilabel(app.general_stationGrid);
            app.general_stationNameLabel.Text = 'Nome:';
            app.general_stationName = uieditfield(app.general_stationGrid, 'text');
            app.general_stationName.ValueChangedFcn = createCallbackFcn(app, @general_ParameterChanged, true);
            app.general_stationName.Enable = 'off';
            app.general_stationTypeLabel = uilabel(app.general_stationGrid);
            app.general_stationTypeLabel.Text = 'Tipo:';
            app.general_stationType = uidropdown(app.general_stationGrid);
            app.general_stationType.Items = {'Fixed', 'Mobile'};
            app.general_stationType.ValueChangedFcn = createCallbackFcn(app, @general_ParameterChanged, true);
            app.general_stationType.Enable = 'off';
            app.general_stationType.Value = 'Fixed';
            app.general_stationLatitudeLabel = uilabel(app.general_stationGrid);
            app.general_stationLatitudeLabel.Text = 'Latitude (ª):';
            app.general_stationLatitude = uieditfield(app.general_stationGrid, 'numeric');
            app.general_stationLatitude.ValueDisplayFormat = '%.6f';
            app.general_stationLatitude.ValueChangedFcn = createCallbackFcn(app, @general_ParameterChanged, true);
            app.general_stationLatitude.Tag = 'task_Editable';
            app.general_stationLatitude.Enable = 'off';
            app.general_stationLatitude.Value = -1;
            app.general_stationLongitudeLabel = uilabel(app.general_stationGrid);
            app.general_stationLongitudeLabel.Text = 'Longitude (º):';
            app.general_stationLongitude = uieditfield(app.general_stationGrid, 'numeric');
            app.general_stationLongitude.ValueDisplayFormat = '%.6f';
            app.general_stationLongitude.ValueChangedFcn = createCallbackFcn(app, @general_ParameterChanged, true);
            app.general_stationLongitude.Tag = 'task_Editable';
            app.general_stationLongitude.Enable = 'off';
            app.general_stationLongitude.Value = -1;
            app.general_lastSessionInfo = uicheckbox(app.general_stationGrid);
            app.general_lastSessionInfo.ValueChangedFcn = createCallbackFcn(app, @general_ParameterChanged, true);
            app.general_lastSessionInfo.Enable = 'off';
            app.general_lastSessionInfo.Text = 'Leitura dados armazenados na última sessão.';
            app.general_versionLabel = uilabel(app.SubGrid2);
            app.general_versionLabel.Text = 'SERVIÇO DE COMUNICAÇÃO (TCP)';
            app.general_versionLock = uiimage(app.SubGrid2);
            app.general_versionLock.ImageClickedFcn = createCallbackFcn(app, @general_PanelLockControl, true);
            app.general_versionLock.ImageSource = 'lockClose_32.png';
            app.general_versionPanel = uipanel(app.SubGrid2);
            app.server_Grid = uigridlayout(app.general_versionPanel);
            app.server_StatusLabel = uilabel(app.server_Grid);
            app.server_StatusLabel.Text = 'Estado:';
            app.server_Status = uidropdown(app.server_Grid);
            app.server_Status.Items = {'ON', 'OFF'};
            app.server_Status.ValueChangedFcn = createCallbackFcn(app, @general_ParameterChanged, true);
            app.server_Status.Enable = 'off';
            app.server_Status.Value = 'ON';
            app.server_KeyLabel = uilabel(app.server_Grid);
            app.server_KeyLabel.Text = 'Chave:';
            app.server_Key = uieditfield(app.server_Grid, 'text');
            app.server_Key.ValueChangedFcn = createCallbackFcn(app, @general_ParameterChanged, true);
            app.server_Key.Enable = 'off';
            app.server_ClientListLabel = uilabel(app.server_Grid);
            app.server_ClientListLabel.Text = {'Lista de clientes:'; '(valores separados por vírgula)'};
            app.server_ClientList = uieditfield(app.server_Grid, 'text');
            app.server_ClientList.ValueChangedFcn = createCallbackFcn(app, @general_ParameterChanged, true);
            app.server_ClientList.Enable = 'off';
            app.server_IPLabel = uilabel(app.server_Grid);
            app.server_IPLabel.Text = 'IP (OpenVPN):';
            app.server_IP = uieditfield(app.server_Grid, 'text');
            app.server_IP.ValueChangedFcn = createCallbackFcn(app, @general_ParameterChanged, true);
            app.server_IP.Enable = 'off';
            app.server_PortLabel = uilabel(app.server_Grid);
            app.server_PortLabel.Text = 'Porta:';
            app.server_Port = uieditfield(app.server_Grid, 'numeric');
            app.server_Port.Limits = [1 65535];
            app.server_Port.RoundFractionalValues = 'on';
            app.server_Port.ValueDisplayFormat = '%d';
            app.server_Port.ValueChangedFcn = createCallbackFcn(app, @general_ParameterChanged, true);
            app.server_Port.Enable = 'off';
            app.server_Port.Value = 1;
            app.SubTab3 = uitab(app.SubTabGroup);
            app.SubTab3.Title = 'PLOT';
            app.SubGrid3 = uigridlayout(app.SubTab3);
            app.plot_TiledSpacingLabel = uilabel(app.SubGrid3);
            app.plot_TiledSpacingLabel.Text = 'ESPAÇAMENTO ENTRE EIXOS';
            app.plot_TiledSpacing = uidropdown(app.SubGrid3);
            app.plot_TiledSpacing.Items = {'loose', 'compact', 'tight', 'none'};
            app.plot_TiledSpacing.ValueChangedFcn = createCallbackFcn(app, @plot_TiledSpacingValueChanged, true);
            app.plot_TiledSpacing.Value = 'compact';
            app.plot_colorsLabel = uilabel(app.SubGrid3);
            app.plot_colorsLabel.Text = 'CORES';
            app.plot_colorsPanel = uipanel(app.SubGrid3);
            app.plot_colorsGrid = uigridlayout(app.plot_colorsPanel);
            app.plot_colorsMinHoldLabel = uilabel(app.plot_colorsGrid);
            app.plot_colorsMinHoldLabel.Text = 'MinHold';
            app.plot_colorsMinHold = uicolorpicker(app.plot_colorsGrid);
            app.plot_colorsMinHold.Value = [0.2902 0.5647 0.8863];
            app.plot_colorsMinHold.ValueChangedFcn = createCallbackFcn(app, @plot_colorsMinHoldValueChanged, true);
            app.plot_colorsAverageLabel = uilabel(app.plot_colorsGrid);
            app.plot_colorsAverageLabel.Text = 'Average';
            app.plot_colorsAverage = uicolorpicker(app.plot_colorsGrid);
            app.plot_colorsAverage.Value = [0 0.8 0.4];
            app.plot_colorsAverage.ValueChangedFcn = createCallbackFcn(app, @plot_colorsMinHoldValueChanged, true);
            app.plot_colorsMaxHoldLabel = uilabel(app.plot_colorsGrid);
            app.plot_colorsMaxHoldLabel.Text = 'MaxHold';
            app.plot_colorsMaxHold = uicolorpicker(app.plot_colorsGrid);
            app.plot_colorsMaxHold.Value = [1 0.3608 0.6784];
            app.plot_colorsMaxHold.ValueChangedFcn = createCallbackFcn(app, @plot_colorsMinHoldValueChanged, true);
            app.plot_colorsClearWriteLabel = uilabel(app.plot_colorsGrid);
            app.plot_colorsClearWriteLabel.Text = 'ClearWrite';
            app.plot_colorsClearWrite = uicolorpicker(app.plot_colorsGrid);
            app.plot_colorsClearWrite.Value = [1 1 0.0706];
            app.plot_colorsClearWrite.ValueChangedFcn = createCallbackFcn(app, @plot_colorsMinHoldValueChanged, true);
            app.plot_WaterfallPanel = uipanel(app.SubGrid3);
            app.plot_WaterfallGrid = uigridlayout(app.plot_WaterfallPanel);
            app.plot_WaterfallColormapLabel = uilabel(app.plot_WaterfallGrid);
            app.plot_WaterfallColormapLabel.Text = 'Mapa de cor:';
            app.plot_WaterfallColormap = uidropdown(app.plot_WaterfallGrid);
            app.plot_WaterfallColormap.Items = {'gray', 'hot', 'jet', 'summer', 'turbo', 'winter'};
            app.plot_WaterfallColormap.ValueChangedFcn = createCallbackFcn(app, @plot_WaterfallColormapValueChanged, true);
            app.plot_WaterfallColormap.Value = 'hot';
            app.plot_WaterfallDepthLabel = uilabel(app.plot_WaterfallGrid);
            app.plot_WaterfallDepthLabel.Text = 'Profundidade:';
            app.plot_WaterfallDepth = uidropdown(app.plot_WaterfallGrid);
            app.plot_WaterfallDepth.Items = {'64', '128', '256', '512'};
            app.plot_WaterfallDepth.ValueChangedFcn = createCallbackFcn(app, @plot_WaterfallColormapValueChanged, true);
            app.plot_WaterfallDepth.Value = '64';
            app.plot_IntegrationLabel = uilabel(app.SubGrid3);
            app.plot_IntegrationLabel.Text = 'INTEGRAÇÃO';
            app.plot_IntegrationPanel = uipanel(app.SubGrid3);
            app.plot_IntegrationGrid = uigridlayout(app.plot_IntegrationPanel);
            app.plot_IntegrationTraceLabel = uilabel(app.plot_IntegrationGrid);
            app.plot_IntegrationTraceLabel.Text = 'Traço médio:';
            app.plot_IntegrationTrace = uieditfield(app.plot_IntegrationGrid, 'numeric');
            app.plot_IntegrationTrace.Limits = [3 100];
            app.plot_IntegrationTrace.RoundFractionalValues = 'on';
            app.plot_IntegrationTrace.ValueDisplayFormat = '%d';
            app.plot_IntegrationTrace.ValueChangedFcn = createCallbackFcn(app, @plot_WaterfallColormapValueChanged, true);
            app.plot_IntegrationTrace.Value = 10;
            app.plot_IntegrationTimeLabel = uilabel(app.plot_IntegrationGrid);
            app.plot_IntegrationTimeLabel.Text = 'Tempo médio escrita:';
            app.plot_IntegrationTime = uieditfield(app.plot_IntegrationGrid, 'numeric');
            app.plot_IntegrationTime.Limits = [3 100];
            app.plot_IntegrationTime.RoundFractionalValues = 'on';
            app.plot_IntegrationTime.ValueDisplayFormat = '%d';
            app.plot_IntegrationTime.ValueChangedFcn = createCallbackFcn(app, @plot_WaterfallColormapValueChanged, true);
            app.plot_IntegrationTime.Value = 10;
            app.configPlotRefresh = uiimage(app.SubGrid3);
            app.configPlotRefresh.ImageClickedFcn = createCallbackFcn(app, @configPlotRefreshImageClicked, true);
            app.configPlotRefresh.Visible = 'off';
            app.configPlotRefresh.Tooltip = {''};
            app.configPlotRefresh.ImageSource = 'Refresh_18.png';
            app.plot_WaterfallLabel = uilabel(app.SubGrid3);
            app.plot_WaterfallLabel.Text = 'WATERFALL';
            app.SubTab4 = uitab(app.SubTabGroup);
            app.SubTab4.Title = 'MAPEAMENTO DE PASTAS';
            app.SubGrid4 = uigridlayout(app.SubTab4);
            app.DATAHUBPOSTLabel = uilabel(app.SubGrid4);
            app.DATAHUBPOSTLabel.Text = 'DATAHUB - POST';
            app.DataHubPOST = uieditfield(app.SubGrid4, 'text');
            app.DataHubPOST.Editable = 'off';
            app.DataHubPOSTButton = uiimage(app.SubGrid4);
            app.DataHubPOSTButton.ImageClickedFcn = createCallbackFcn(app, @Config_FolderButtonPushed, true);
            app.DataHubPOSTButton.Tag = 'DataHub_POST';
            app.DataHubPOSTButton.Enable = 'off';
            app.DataHubPOSTButton.ImageSource = 'folder-opened-16px.svg';
            app.userPathLabel = uilabel(app.SubGrid4);
            app.userPathLabel.Text = 'PASTA DO USUÁRIO';
            app.userPath = uieditfield(app.SubGrid4, 'text');
            app.userPath.Editable = 'off';
            app.userPathButton = uiimage(app.SubGrid4);
            app.userPathButton.ImageClickedFcn = createCallbackFcn(app, @Config_FolderButtonPushed, true);
            app.userPathButton.Tag = 'userPath';
            app.userPathButton.Enable = 'off';
            app.userPathButton.ImageSource = 'folder-opened-16px.svg';
            app.DockModule = uigridlayout(app.GridLayout);
            app.DockModule.Visible = 'off';
            app.DockUndockButton = uiimage(app.DockModule);
            app.DockUndockButton.ImageClickedFcn = createCallbackFcn(app, @onDockModuleGroupButtonClicked, true);
            app.DockUndockButton.Enable = 'off';
            app.DockUndockButton.ImageSource = 'Undock_18White.png';
            app.DockCloseButton = uiimage(app.DockModule);
            app.DockCloseButton.ImageClickedFcn = createCallbackFcn(app, @onDockModuleGroupButtonClicked, true);
            app.DockCloseButton.ImageSource = 'Delete_12SVG_white.svg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winConfig(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end