classdef winServer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        DockModule                 matlab.ui.container.GridLayout
        dockModule_Undock          matlab.ui.control.Image
        dockModule_Close           matlab.ui.control.Image
        TabGroup                   matlab.ui.container.TabGroup
        TabGroupGrid               matlab.ui.container.Tab
        Document                   matlab.ui.container.GridLayout
        communicationTable         matlab.ui.control.Table
        communicationTableRefresh  matlab.ui.control.Image
        communicationTableLabel    matlab.ui.control.Label
        serverInfo                 matlab.ui.control.TextArea
        serverInfoLabel            matlab.ui.control.Label
        Toolbar                    matlab.ui.container.GridLayout
        toolButton_edit            matlab.ui.control.Button
        toolLampLabel              matlab.ui.control.Label
        toolLamp                   matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Role = 'secondaryApp'
    end


    properties (Access = public)
        %-----------------------------------------------------------------%
        Container
        isDocked = false
        mainApp
        jsBackDoor
        progressDialog
    end


    methods (Access = public)
        %-----------------------------------------------------------------%
        function ipcSecondaryJSEventsHandler(app, event)
            try
                switch event.HTMLEventName
                    case 'renderer'
                        appEngine.activate(app, app.Role)

                    otherwise
                        error('UnexpectedEvent')
                end

            catch ME
                ui.Dialog(app.UIFigure, 'error', ME.message);
            end
        end

        %-----------------------------------------------------------------%
        function applyJSCustomizations(app, tabIndex)
            arguments
                app
                tabIndex = 0
            end

            if app.isDocked
                app.progressDialog = app.mainApp.progressDialog;
            else
                sendEventToHTMLSource(app.jsBackDoor, 'startup', app.mainApp.executionMode);
                app.progressDialog = ui.ProgressDialog(app.jsBackDoor);
            end

            appName = class(app);

            % Grid botões "dock":
            if app.isDocked
                elToModify = {app.DockModule};
                elDataTag  = ui.CustomizationBase.getElementsDataTag(elToModify);
                if ~isempty(elDataTag)
                    sendEventToHTMLSource(app.jsBackDoor, 'initializeComponents', { ...
                        struct('appName', appName, 'dataTag', elDataTag{1}, 'style', struct('transition', 'opacity 2s ease', 'opacity', '0.5')), ...
                    });
                end
            end
        end

        %-----------------------------------------------------------------%
        function initializeAppProperties(app)
            % ...
        end

        %-----------------------------------------------------------------%
        function initializeUIComponents(app)
            if ~strcmp(app.mainApp.executionMode, 'webApp')
                app.dockModule_Undock.Enable = 1;
            end
        end

        %-----------------------------------------------------------------%
        function applyInitialLayout(app)
            updateLayout(app)
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function updateLayout(app)
            if isempty(app.mainApp.tcpServer)
                app.serverInfo.Value = '';
                app.communicationTableRefresh.Visible = 0;

                app.toolLamp.Color = [.64 .08 .18];
                app.toolLampLabel.Text = 'Servidor não está em execução.';

                app.communicationTable.Data = table('Size', [0, 8],                                                                                    ...
                                               'VariableTypes', {'string', 'string', 'double', 'string', 'string', 'string', 'double', 'string'}, ...
                                               'VariableNames', {'Timestamp', 'ClientAddress', 'ClientPort', 'Message', 'ClientName', 'Request', 'NumBytesWritten', 'Status'});
                set(app.toolButton_edit, 'Text', 'Iniciar servidor', 'Icon', 'play_32.png')

            elseif isempty(app.mainApp.tcpServer.Server)
                app.serverInfo.Value = '';
                app.communicationTableRefresh.Visible = 0;

                app.toolLamp.Color = [.5 .5 .5];
                app.toolLampLabel.Text = sprintf('Servidor ainda não está em execução, apesar do objeto "class.tcpServerLib" já ter sido criado. Será realizada uma nova tentativa para executá-lo a cada %d segundos.', class.Constants.tcpServerPeriod);

                app.communicationTable.Data = app.mainApp.tcpServer.LOG;
                set(app.toolButton_edit, 'Text', 'Excluir objeto', 'Icon', 'Delete_32Red.png')

            else
                app.serverInfo.Value = util.HtmlTextGenerator.Server(app.mainApp.tcpServer);
                app.communicationTableRefresh.Visible = 1;

                app.toolLamp.Color = [.47 .67 .19];
                app.toolLampLabel.Text = sprintf('Servidor em execução desde %s.', char(app.mainApp.tcpServer.Time));

                app.communicationTable.Data = app.mainApp.tcpServer.LOG;
                set(app.toolButton_edit, 'Text', 'Parar servidor', 'Icon', 'stop_32.png')
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)

            try
                appEngine.boot(app, app.Role, mainApp)
            catch ME
                ui.Dialog(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)

            ipcMainMatlabCallsHandler(app.mainApp, app, 'closeFcn', 'SERVER')
            delete(app)
            
        end

        % Button pushed function: toolButton_edit
        function toolButtonPushed_edit(app, event)
            
            if isempty(app.mainApp.tcpServer)
                app.mainApp.tcpServer = class.tcpServerLib(app.mainApp);
            
            else
                stop(app.mainApp.tcpServer.Timer)
                delete(app.mainApp.tcpServer.Timer)
                delete(app.mainApp.tcpServer.Server)
                
                app.mainApp.tcpServer = [];
            end

            updateLayout(app)

        end

        % Image clicked function: communicationTableRefresh
        function communicationTableRefreshImageClicked(app, event)
            
            updateLayout(app)

        end

        % Image clicked function: dockModule_Close, dockModule_Undock
        function DockModuleGroup_ButtonPushed(app, event)
            
            [idx, auxAppTag, relatedButton] = getAppInfoFromHandle(app.mainApp.tabGroupController, app);

            switch event.Source
                case app.dockModule_Undock
                    appGeneral = app.mainApp.General;
                    appGeneral.operationMode.Dock = false;

                    inputArguments = ipcMainMatlabCallsHandler(app.mainApp, app, 'dockButtonPushed', auxAppTag);
                    app.mainApp.tabGroupController.Components.appHandle{idx} = [];
                    
                    openModule(app.mainApp.tabGroupController, relatedButton, false, appGeneral, inputArguments{:})
                    closeModule(app.mainApp.tabGroupController, auxAppTag, app.mainApp.General, 'undock')
                    
                    delete(app)

                case app.dockModule_Close
                    closeModule(app.mainApp.tabGroupController, auxAppTag, app.mainApp.General)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta';
            app.UIFigure.Icon = 'icon_32.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Toolbar = uigridlayout(app.GridLayout);
            app.toolLamp = uilamp(app.Toolbar);
            app.toolLampLabel = uilabel(app.Toolbar);
            app.toolLampLabel.Text = 'Desconectado';
            app.toolButton_edit = uibutton(app.Toolbar, 'push');
            app.toolButton_edit.ButtonPushedFcn = createCallbackFcn(app, @toolButtonPushed_edit, true);
            app.toolButton_edit.Icon = 'play_32.png';
            app.toolButton_edit.Text = 'Iniciar servidor';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroupGrid = uitab(app.TabGroup);
            app.TabGroupGrid.Title = 'SERVIDOR';
            app.Document = uigridlayout(app.TabGroupGrid);
            app.serverInfoLabel = uilabel(app.Document);
            app.serverInfoLabel.Text = 'CARACTERÍSTICAS:';
            app.serverInfo = uitextarea(app.Document);
            app.serverInfo.Editable = 'off';
            app.communicationTableLabel = uilabel(app.Document);
            app.communicationTableLabel.Text = 'COMUNICAÇÃO:';
            app.communicationTableRefresh = uiimage(app.Document);
            app.communicationTableRefresh.ImageClickedFcn = createCallbackFcn(app, @communicationTableRefreshImageClicked, true);
            app.communicationTableRefresh.Tooltip = {'Atualiza registro de comunicação'};
            app.communicationTableRefresh.ImageSource = 'Refresh_18.png';
            app.communicationTable = uitable(app.Document);
            app.communicationTable.ColumnName = {'INSTANTE'; 'IP'; 'PORTA'; 'MENSAGEM'; 'CLIENTE'; 'REQUISIÇÃO'; 'BYTES'; 'ESTADO'};
            app.communicationTable.RowName = {};
            app.DockModule = uigridlayout(app.GridLayout);
            app.DockModule.Visible = 'off';
            app.dockModule_Close = uiimage(app.DockModule);
            app.dockModule_Close.ImageClickedFcn = createCallbackFcn(app, @DockModuleGroup_ButtonPushed, true);
            app.dockModule_Close.Tag = 'DRIVETEST';
            app.dockModule_Close.Tooltip = {'Fecha módulo'};
            app.dockModule_Close.ImageSource = 'Delete_12SVG_white.svg';
            app.dockModule_Undock = uiimage(app.DockModule);
            app.dockModule_Undock.ImageClickedFcn = createCallbackFcn(app, @DockModuleGroup_ButtonPushed, true);
            app.dockModule_Undock.Tag = 'DRIVETEST';
            app.dockModule_Undock.Enable = 'off';
            app.dockModule_Undock.Tooltip = {'Reabre módulo em outra janela'};
            app.dockModule_Undock.ImageSource = 'Undock_18White.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winServer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end