classdef winTaskList < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        DockModule                 matlab.ui.container.GridLayout
        DockCloseButton            matlab.ui.control.Image
        DockUndockButton           matlab.ui.control.Image
        SubTabGroup                matlab.ui.container.TabGroup
        SubTab1                    matlab.ui.container.Tab
        SubGrid1                   matlab.ui.container.GridLayout
        PanelGrid                  matlab.ui.container.GridLayout
        BandSpecificInfo_Grid      matlab.ui.container.GridLayout
        FindPeaks_Panel            matlab.ui.container.Panel
        FindPeaks_Grid             matlab.ui.container.GridLayout
        FindPeaks_BW               matlab.ui.control.Spinner
        FindPeaks_BWLabel          matlab.ui.control.Label
        FindPeaks_Distance         matlab.ui.control.Spinner
        FindPeaks_DistanceLabel    matlab.ui.control.Label
        FindPeaks_Prominence       matlab.ui.control.Spinner
        FindPeaks_ProminenceLabel  matlab.ui.control.Label
        FindPeaks_nSweeps          matlab.ui.control.Spinner
        FindPeaks_nSweepsLabel     matlab.ui.control.Label
        FindPeaks_Type             matlab.ui.control.DropDown
        FindPeaks_TypeLabel        matlab.ui.control.Label
        FindPeaks_PanelLabel       matlab.ui.control.Label
        RevisitTime                matlab.ui.control.NumericEditField
        RevisitTimeLabel           matlab.ui.control.Label
        LevelUnit                  matlab.ui.control.DropDown
        LevelUnitLabel             matlab.ui.control.Label
        Detector                   matlab.ui.control.DropDown
        DetectorLabel              matlab.ui.control.Label
        VBW                        matlab.ui.control.DropDown
        VBWLabel                   matlab.ui.control.Label
        RFMode                     matlab.ui.control.DropDown
        RFModeLabel                matlab.ui.control.Label
        IntegrationFactor          matlab.ui.control.NumericEditField
        IntegrationFactorLabel     matlab.ui.control.Label
        TraceMode                  matlab.ui.control.DropDown
        TraceModeLabel             matlab.ui.control.Label
        Resolution                 matlab.ui.control.NumericEditField
        ResolutionLabel            matlab.ui.control.Label
        StepWidth                  matlab.ui.control.NumericEditField
        StepWidthLabel             matlab.ui.control.Label
        FreqStop                   matlab.ui.control.NumericEditField
        FreqStopLabel              matlab.ui.control.Label
        FreqStart                  matlab.ui.control.NumericEditField
        FreqStartLabel             matlab.ui.control.Label
        ObservationSamples         matlab.ui.control.NumericEditField
        ObservationSamplesLabel    matlab.ui.control.Label
        Description                matlab.ui.control.EditField
        DescriptionLabel           matlab.ui.control.Label
        ID                         matlab.ui.control.NumericEditField
        IDLabel                    matlab.ui.control.Label
        MaskTrigger                matlab.ui.control.DropDown
        MaskTriggerLabel           matlab.ui.control.Label
        Status                     matlab.ui.control.DropDown
        StatusLabel                matlab.ui.control.Label
        Tab2_PanelGrid             matlab.ui.container.GridLayout
        GPS_Panel                  matlab.ui.container.Panel
        GPS_Grid                   matlab.ui.container.GridLayout
        GPS_RevisitTime            matlab.ui.control.NumericEditField
        GPS_RevisitTimeLabel       matlab.ui.control.Label
        GPS_manualLongitude        matlab.ui.control.NumericEditField
        GPS_manualLongitudeLabel   matlab.ui.control.Label
        GPS_manualLatitude         matlab.ui.control.NumericEditField
        GPS_manualLatitudeLabel    matlab.ui.control.Label
        gpsMode                    matlab.ui.control.DropDown
        gpsModeLabel               matlab.ui.control.Label
        ObservationPanel           matlab.ui.container.Panel
        ObservationPanel_Grid      matlab.ui.container.GridLayout
        SpecificTime_Grid          matlab.ui.container.GridLayout
        SpecificTime_Mark2         matlab.ui.control.Label
        SpecificTime_Mark1         matlab.ui.control.Label
        SpecificTime_Spinner4      matlab.ui.control.Spinner
        SpecificTime_Spinner3      matlab.ui.control.Spinner
        SpecificTime_DatePicker2   matlab.ui.control.DatePicker
        SpecificTime_Spinner2      matlab.ui.control.Spinner
        SpecificTime_Spinner1      matlab.ui.control.Spinner
        SpecificTime_DatePicker1   matlab.ui.control.DatePicker
        Duration_Grid              matlab.ui.container.GridLayout
        Duration                   matlab.ui.control.NumericEditField
        DurationUnit               matlab.ui.control.DropDown
        ObservationType            matlab.ui.control.DropDown
        ObservationTypeLabel       matlab.ui.control.Label
        ObservationLabel           matlab.ui.control.Label
        BitsPerPoint               matlab.ui.control.DropDown
        BitsPerPointLabel          matlab.ui.control.Label
        Name                       matlab.ui.control.EditField
        NameLabel                  matlab.ui.control.Label
        TreeLabel                  matlab.ui.control.Label
        TreeGrid                   matlab.ui.container.GridLayout
        TreeMoveDown               matlab.ui.control.Image
        TreeMoveUp                 matlab.ui.control.Image
        TreeDelNode                matlab.ui.control.Image
        TreeAddBandNode            matlab.ui.control.Image
        TreeAddTaskNode            matlab.ui.control.Image
        Tree                       matlab.ui.container.Tree
        ModePanel                  matlab.ui.container.ButtonGroup
        ModeButtonEdit             matlab.ui.control.RadioButton
        ModeButtonView             matlab.ui.control.RadioButton
        ModePanelLabel             matlab.ui.control.Label
        Toolbar                    matlab.ui.container.GridLayout
        ConfirmEditionButton       matlab.ui.control.Button
        ExportButton               matlab.ui.control.Image
        ImportButton               matlab.ui.control.Image
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Role = 'secondaryApp'
        Context = 'TASK_EDIT'
    end


    properties (Access = public)
        %-----------------------------------------------------------------%
        Container
        isDocked = false
        mainApp
        jsBackDoor
        progressDialog
        taskList
        editedList
    end


    methods (Access = public)
        %-----------------------------------------------------------------%
        function ipcSecondaryJSEventsHandler(app, event)
            try
                switch event.HTMLEventName
                    case 'renderer'
                        appEngine.activate(app, app.Role)

                    otherwise
                        ipcMainJSEventsHandler(app.mainApp, event)
                end

            catch ME
                ui.Dialog(app.UIFigure, 'error', ME.message);
            end
        end

        %-----------------------------------------------------------------%
        function applyJSCustomizations(app, tabIndex)
            if app.SubTabGroup.UserData.isTabInitialized(tabIndex)
                return
            end
            app.SubTabGroup.UserData.isTabInitialized(tabIndex) = true;
            
            switch tabIndex
                case 1
                    appName = class(app);
                    elToModify = {
                        app.ImportButton;
                        app.ExportButton;
                        app.DockUndockButton;
                        app.DockCloseButton
                    };
                    ui.CustomizationBase.getElementsDataTag(elToModify);

                    try
                        sendEventToHTMLSource(app.jsBackDoor, 'initializeComponents', { ...
                            struct('appName', appName, 'dataTag', app.ImportButton.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Importa lista de tarefas')), ...
                            struct('appName', appName, 'dataTag', app.ExportButton.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Exporta lista de tarefas')), ...
                            struct('appName', appName, 'dataTag', app.DockUndockButton.UserData.id, 'tooltip', struct('defaultPosition', 'bottom', 'textContent', 'Reabre módulo em outra janela')), ...
                            struct('appName', appName, 'dataTag', app.DockCloseButton.UserData.id, 'tooltip', struct('defaultPosition', 'bottom', 'textContent', 'Fecha módulo')) ...
                        });
                    catch
                    end

                otherwise
                    % ...
            end
        end

        %-----------------------------------------------------------------%
        function initializeAppProperties(app)
            app.taskList   = util.TaskScriptIO.loadScriptList(app.mainApp.rootFolder, 'auxApp.winTaskList');
            app.editedList = app.taskList;
        end

        %-----------------------------------------------------------------%
        function initializeUIComponents(app)
            if ~strcmp(app.mainApp.executionMode, 'webApp')
                app.DockUndockButton.Enable = 1;
            end
        end

        %-----------------------------------------------------------------%
        function applyInitialLayout(app)
            TreeBuilding(app, [])
            focus(app.Tree)
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function TreeBuilding(app, SelectedNode)
            if ~isempty(app.Tree.Children)
                delete(app.Tree.Children)
            end

            % Tree creation
            for ii = 1:numel(app.editedList)
                taskNode = uitreenode(app.Tree, 'Text', app.editedList(ii).Name, 'NodeData', ii, 'UserData', 1:numel(app.editedList(ii).Band));

                for jj = 1:numel(app.editedList(ii).Band)
                    uitreenode(taskNode, 'Text', TreeBuilding_nodeText(app, ii, jj), 'NodeData', ii, 'UserData', jj);
                end
            end
            TreeBuilding_addStyle(app)

            % SelectedNode
            if ~isempty(app.Tree.Children)
                if ~isempty(SelectedNode)
                    idx1 = SelectedNode(1);
                    idx2 = SelectedNode(2);
                else
                    idx1 = 1;
                    idx2 = -1;
                end
                
                if idx2 == -1
                    app.Tree.SelectedNodes = app.Tree.Children(idx1);
                else
                    app.Tree.SelectedNodes = app.Tree.Children(idx1).Children(idx2);
                end

                TreeSelectionChanged(app)                                   % internal "Layout(app)" call
                expand(app.Tree.Children(idx1))
            else
                Layout(app)
            end
        end

        %-----------------------------------------------------------------%
        function nodeText = TreeBuilding_nodeText(app, idx1, idx2)
            nodeText = sprintf('ID %d: %.3f - %.3f MHz', app.editedList(idx1).Band(idx2).ID,                ...
                                                         app.editedList(idx1).Band(idx2).FreqStart ./ 1e+6, ...
                                                         app.editedList(idx1).Band(idx2).FreqStop  ./ 1e+6);
        end

        %-----------------------------------------------------------------%
        function TreeBuilding_addStyle(app)
            if ~isempty(app.Tree.StyleConfigurations)
                removeStyle(app.Tree)
            end

            DisableNodes = [];
            for ii = 1:numel(app.editedList)
                for jj = 1:numel(app.editedList(ii).Band)
                    if ~app.editedList(ii).Band(jj).Enable
                        DisableNodes = [DisableNodes, app.Tree.Children(ii).Children(jj)];
                    end
                end
            end

            if ~isempty(DisableNodes)
                s = uistyle('FontColor', [.5 .5 .5]);
                addStyle(app.Tree, s, 'node', DisableNodes)
            end
        end

        %-----------------------------------------------------------------%
        function Layout(app)
            if isempty(app.Tree.SelectedNodes)
                set(app.Tab2_PanelGrid.Children,        'Enable', 0)
                set(app.BandSpecificInfo_Grid.Children, 'Enable', 0)

            else
                set(app.Tab2_PanelGrid.Children,        'Enable', 1)

                if isscalar(app.Tree.SelectedNodes.UserData)
                    set(app.BandSpecificInfo_Grid.Children, 'Enable', 1)

                    switch app.ObservationType.Value
                        case 'Quantidade específica de amostras'
                            app.ObservationSamples.Enable = 'on';
                        otherwise
                            app.ObservationSamples.Enable = 'off';
                    end

                elseif numel(app.Tree.SelectedNodes.UserData) > 1
                    set(app.BandSpecificInfo_Grid.Children, 'Enable', 0)
                end
            end
        end

        %-----------------------------------------------------------------%
        function ObservationTimeLayout(app)
            switch app.ObservationType.Value
                case 'Duração' % "Duration"
                    app.Tab2_PanelGrid.RowHeight{6} = 94; 
                    app.ObservationPanel_Grid.RowHeight{3} = 22;
                    set([app.Duration, app.DurationUnit], 'Enable', 1, 'Visible', 1)
                    set(app.SpecificTime_Grid.Children, 'Enable', 0, 'Visible', 0)
                    app.ObservationSamples.Enable = 0;

                %---------------------------------------------------------%
                case 'Período específico' % "Time"
                    SpecificTimePanel_editable(app)

                    app.Tab2_PanelGrid.RowHeight{6} = 122;
                    app.ObservationPanel_Grid.RowHeight{3} = 0;
                    set([app.Duration, app.DurationUnit], 'Enable', 0, 'Visible', 0)
                    app.ObservationSamples.Enable = 0;

                %---------------------------------------------------------%
                case 'Quantidade específica de amostras' % "Samples"
                    app.Tab2_PanelGrid.RowHeight{6} = 66; 
                    app.ObservationPanel_Grid.RowHeight{3} = 0;
                    set([app.Duration, app.DurationUnit], 'Enable', 0, 'Visible', 0)
                    set(app.SpecificTime_Grid.Children, 'Enable', 0, 'Visible', 0)
                    app.ObservationSamples.Enable = 1;
            end
        end

        %-----------------------------------------------------------------%
        function updateDuration(app, idx1)
            Duration_sec = app.editedList(idx1).Observation.Duration;
            if isempty(Duration_sec)
                Duration_sec = 600;
            end

            if Duration_sec >= 3600
                app.Duration.Value = Duration_sec ./ 3600;
                set(app.DurationUnit, Items={'min', 'hr'}, Value='hr')
            else
                app.Duration.Value = Duration_sec ./ 60;
                set(app.DurationUnit, Items={'min', 'hr'}, Value='min')
            end

            if app.ModeButtonView.Value
                app.DurationUnit.Items = {app.DurationUnit.Value};
            end
        end

        %-----------------------------------------------------------------%
        function updateObservationTime(app, idx1)
            BeginTime = datetime(app.editedList(idx1).Observation.BeginTime, "InputFormat", "dd/MM/yyyy HH:mm:ss", "Format", "dd/MM/yyyy HH:mm:ss");
            EndTime   = datetime(app.editedList(idx1).Observation.EndTime,   "InputFormat", "dd/MM/yyyy HH:mm:ss", "Format", "dd/MM/yyyy HH:mm:ss");

            timeFlag  = 1;
            if isnat(BeginTime) && isnat(EndTime)
                BeginTime = datetime('now');
                EndTime   = BeginTime;
            elseif isnat(BeginTime)
                BeginTime = datetime('now');
            elseif isnat(EndTime)
                EndTime   = datetime('now');
            else
                timeFlag  = 0; 
            end

            app.SpecificTime_DatePicker1.Value = BeginTime;
            app.SpecificTime_DatePicker2.Value = EndTime;

            try
                if timeFlag
                    error('timeFlag')
                end

                app.SpecificTime_Spinner1.Value = hour(BeginTime);
                app.SpecificTime_Spinner2.Value = minute(BeginTime);
                app.SpecificTime_Spinner3.Value = hour(EndTime);
                app.SpecificTime_Spinner4.Value = minute(EndTime);
            catch
                app.SpecificTime_Spinner1.Value = 0;
                app.SpecificTime_Spinner2.Value = 0;
                app.SpecificTime_Spinner3.Value = 23;
                app.SpecificTime_Spinner4.Value = 59;
            end
        end

        %-----------------------------------------------------------------%
        function SpecificTimePanel_editable(app)
            if app.ModeButtonView.Value
                set(app.SpecificTime_Grid.Children, Enable=0, Visible=1)
            else
                set(app.SpecificTime_Grid.Children, Enable=1, Visible=1)
            end
        end

        %-----------------------------------------------------------------%
        function FindPeaksPanel_editable(app)
            if app.ModeButtonView.Value
                set(findobj(app.FindPeaks_Grid, 'Type', 'uispinner'), Enable=0)
            else
                set(findobj(app.FindPeaks_Grid, 'Type', 'uispinner'), Enable=1)
            end
        end

        %-----------------------------------------------------------------%
        function SpanCheck(app)
            span = (app.FreqStop.Value - app.FreqStart.Value)*1e+6;

            if span <= 0
                app.FreqStart.FontColor = [1 0 0];
                app.FreqStop.FontColor  = [1 0 0];
                app.StepWidth.Enable    = 0;
            else
                app.FreqStart.FontColor = [0 0 0];
                app.FreqStop.FontColor  = [0 0 0];
                app.StepWidth.Enable    = 1;
            end
        end

        %-----------------------------------------------------------------%
        function IntegrationFactorCheck(app)
            switch app.TraceMode.Value
                case 'ClearWrite'
                    set(app.IntegrationFactor, 'Enable', 0, 'Value', 1)

                otherwise
                    app.IntegrationFactor.Enable = 1;

                    if app.IntegrationFactor.Value == 1
                        app.IntegrationFactor.Value = 3;
                    end
            end
        end

        %-----------------------------------------------------------------%
        function newID(app)
            idx1 = app.Tree.SelectedNodes.NodeData;

            for ii = 1:numel(app.editedList(idx1).Band)
                app.editedList(idx1).Band(ii).ID = ii;
            end
        end

        %-----------------------------------------------------------------%
        function update(app)
            appName = class.Constants.appName;
            [~, programDataFolder] = appEngine.util.Path(appName, app.mainApp.rootFolder);
            saveNewFile(app, programDataFolder, false)

            % Atualiza a propriedade do app...
            ipcMainMatlabCallsHandler(app.mainApp, app, 'onTaskListEdit')

            % Fecha o módulo auxiliar "auxApp.winAddTask.mlapp", caso aberto.
            ipcMainMatlabCallsHandler(app.mainApp, app, 'closeFcn', 'TASK_ADD')
        end

        %-----------------------------------------------------------------%
        function saveNewFile(app, Folder, ShowAlert)
            msgError = util.TaskScriptIO.writeScriptFile(Folder, app.taskList);

            if ShowAlert
                if isempty(msgError)
                    ui.Dialog(app.UIFigure, "warning", sprintf('Arquivo <b>TaskList.json</b> salvo na pasta "%s"', Folder));
                else
                    ui.Dialog(app.UIFigure, "error", msgError);
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            
            try
                appEngine.boot(app, app.Role, mainApp)
            catch ME
                ui.Dialog(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            ipcMainMatlabCallsHandler(app.mainApp, app, 'closeFcn', app.Context)
            delete(app)
            
        end

        % Image clicked function: DockCloseButton, DockUndockButton
        function onDockModuleGroupButtonClicked(app, event)
            
            [idx, auxAppTag, relatedButton] = getAppInfoFromHandle(app.mainApp.tabGroupController, app);

            switch event.Source
                case app.DockUndockButton
                    appGeneral = app.mainApp.General;
                    appGeneral.operationMode.Dock = false;
                    
                    inputArguments = ipcMainMatlabCallsHandler(app.mainApp, app, 'dockButtonPushed', auxAppTag);
                    app.mainApp.tabGroupController.Components.appHandle{idx} = [];
                    
                    openModule(app.mainApp.tabGroupController, relatedButton, false, appGeneral, inputArguments{:})
                    closeModule(app.mainApp.tabGroupController, auxAppTag, app.mainApp.General, 'undock')
                    
                    delete(app)

                case app.DockCloseButton
                    closeModule(app.mainApp.tabGroupController, auxAppTag, app.mainApp.General)
            end

        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            
            idx1 = app.Tree.SelectedNodes.NodeData;
            
            if exist('event', 'var') && ~isequal(event.SelectedNodes.NodeData, event.PreviousSelectedNodes.NodeData)
                collapse(app.Tree)
                expand(app.Tree.Children(idx1))
            end

            % Painel "ASPECTOS GERAIS"
            app.Name.Value            = app.editedList(idx1).Name;
            app.BitsPerPoint.Items    = {sprintf('%d bits', app.editedList(idx1).BitsPerSample)};
            
            app.ObservationType.Items = {util.TaskScriptIO.observationTypeLabel(app.editedList(idx1).Observation.Type)};
            ObservationTimeLayout(app)
            
            app.gpsMode.Items         = {app.editedList(idx1).GPS.Type};
            gpsModeValueChanged(app)


            % Painel "ESPECIFICIDADES DO FLUXO SELECIONADO"
            if isscalar(app.Tree.SelectedNodes.UserData)
                idx2 = app.Tree.SelectedNodes.UserData;

                % Ajuste dos itens que são listas suspensas (uidropdown)
                % porque no "MODO DE EDIÇÃO" todos os possíveis valores
                % estão disponíveis para escolha, enquanto que no "MODO DE
                % VISUALIZAÇÃO" ficará disponível apenas o valor indicado
                % no "TaskList.json".

                %---------------------------------------------------------%
                % ## MODO DE VISUALIZAÇÃO ##
                %---------------------------------------------------------%
                if app.ModeButtonView.Value
                    if app.editedList(idx1).Band(idx2).Enable; app.Status.Items = {'ON'};
                    else;                                      app.Status.Items = {'OFF'};
                    end

                    switch app.editedList(idx1).Band(idx2).MaskTrigger.Status
                        case 0; app.MaskTrigger.Items = {'OFF'};
                        case 1; app.MaskTrigger.Items = {'ON - Apenas afere rompimento'};
                        case 2; app.MaskTrigger.Items = {'ON - Afere rompimento e salva em arquivo (caso rompida máscara)'};
                        case 3; app.MaskTrigger.Items = {'ON - Afere rompimento e salva em arquivo'};
                    end

                    app.TraceMode.Items = {app.editedList(idx1).Band(idx2).TraceMode};
                    app.VBW.Items       = {app.editedList(idx1).Band(idx2).VBW};
                    app.Detector.Items  = {app.editedList(idx1).Band(idx2).Detector};
                    app.RFMode.Items    = {app.editedList(idx1).Band(idx2).RFMode};
                    app.LevelUnit.Items = {app.editedList(idx1).Band(idx2).LevelUnit};

                    if isempty(app.editedList(idx1).Band(idx2).MaskTrigger.FindPeaks)
                        app.FindPeaks_Type.Items = {'Valores padrão (appColeta)'};
                    else
                        app.FindPeaks_Type.Items = {'Valores customizados'};
                    end

                %---------------------------------------------------------%
                % ## MODO DE EDIÇÃO ##
                %---------------------------------------------------------%
                else
                    if app.editedList(idx1).Band(idx2).Enable; app.Status.Value = 'ON';
                    else;                                      app.Status.Value = 'OFF';
                    end

                    switch app.editedList(idx1).Band(idx2).MaskTrigger.Status
                        case 0; app.MaskTrigger.Value = 'OFF';
                        case 1; app.MaskTrigger.Value = 'ON - Apenas afere rompimento';
                        case 2; app.MaskTrigger.Value = 'ON - Afere rompimento e salva em arquivo (caso rompida máscara)';
                        case 3; app.MaskTrigger.Value = 'ON - Afere rompimento e salva em arquivo';
                    end

                    app.TraceMode.Value = app.editedList(idx1).Band(idx2).TraceMode;
                    app.VBW.Value       = app.editedList(idx1).Band(idx2).VBW;
                    app.Detector.Value  = app.editedList(idx1).Band(idx2).Detector;
                    app.LevelUnit.Value = app.editedList(idx1).Band(idx2).LevelUnit;
                    app.RFMode.Value    = app.editedList(idx1).Band(idx2).RFMode;

                    if isempty(app.editedList(idx1).Band(idx2).MaskTrigger.FindPeaks)
                        app.FindPeaks_Type.Value = 'Valores padrão (appColeta)';
                    else
                        app.FindPeaks_Type.Value = 'Valores customizados';
                    end
                end


                % Ajustes nos outros campos (que não são listas suspensas), 
                % além de especificidades do campo "Fator integração" e dos
                % parâmetros relacionados à busca de emissões.
    
                app.ObservationSamples.Value = app.editedList(idx1).Band(idx2).ObservationSamples;
                app.ID.Value                 = app.editedList(idx1).Band(idx2).ID;
                app.Description.Value        = app.editedList(idx1).Band(idx2).Description;
                app.RevisitTime.Value        = app.editedList(idx1).Band(idx2).RevisitTime;
                
                app.FreqStart.Value          = app.editedList(idx1).Band(idx2).FreqStart  / 1e+6;
                app.FreqStop.Value           = app.editedList(idx1).Band(idx2).FreqStop   / 1e+6;
                app.StepWidth.Value          = app.editedList(idx1).Band(idx2).StepWidth  / 1e+3;
                SpanCheck(app)

                app.Resolution.Value         = app.editedList(idx1).Band(idx2).Resolution / 1e+3;
                app.IntegrationFactor.Value  = app.editedList(idx1).Band(idx2).IntegrationFactor;

                IntegrationFactorCheck(app)
                FindPeaksDropDownValueChanged(app)
            end


            % LAYOUT
            Layout(app)

            if app.ModeButtonEdit.Value
                set(app.BitsPerPoint,    'Items', {'8 bits', '16 bits', '32 bits'})
                set(app.ObservationType, 'Items', {'Duração', 'Período específico', 'Quantidade específica de amostras'})
                set(app.DurationUnit,    'Items', {'min', 'hr'})
                set(app.gpsMode,         'Items', {'auto', 'manual'})
            end
            
        end

        % Value changed function: ObservationType
        function ObservationTimeValueChanged(app, event)
            
            ObservationTimeLayout(app)
            
            idx1 = app.Tree.SelectedNodes.NodeData;            
            switch app.ObservationType.Value
                case 'Duração'                                              % "Duration"
                    updateDuration(app, idx1)
                    TaskParameterChanged(app, struct('Source', app.Duration))

                case 'Período específico'                                   % "Time"
                    updateObservationTime(app, idx1)
                    TaskParameterChanged(app, struct('Source', app.SpecificTime_DatePicker1))

                case 'Quantidade específica de amostras'                    % "Samples"
                    TaskParameterChanged(app, struct('Source', app.ObservationSamples))
            end
            
        end

        % Value changed function: gpsMode
        function gpsModeValueChanged(app, event)
            
            app.GPS_manualLatitude.Value  = -1;
            app.GPS_manualLongitude.Value = -1;
            app.GPS_RevisitTime.Value     = 60;
            set(app.GPS_Grid.Children, Enable='on')

            % Após a leitura do arquivo "TaskList.json", uma tarefa com GPS
            % automático tem informação vazia de coordenadas geográficas
            % (latitude, longitude). Ao editar o tipo de GPS, trocando de
            % automático para manual, os valores iniciais serão (-1,-1), os
            % quais foram definidos no topo da função. O bloco try/catch
            % evita o erro de preenchimento do componente numérico com um
            % valor vazio.

            idx1 = app.Tree.SelectedNodes.NodeData;

            switch app.gpsMode.Value
                case 'auto'
                    app.GPS_manualLatitude.Enable     = 'off';
                    app.GPS_manualLongitude.Enable    = 'off';
                    app.GPS_RevisitTime.Value         = app.editedList(idx1).GPS.RevisitTime;

                case 'manual'
                    try
                        app.GPS_manualLatitude.Value  = app.editedList(idx1).GPS.Latitude;
                        app.GPS_manualLongitude.Value = app.editedList(idx1).GPS.Longitude;
                    catch
                    end
                    app.GPS_RevisitTime.Enable        = 'off';
            end

            TaskParameterChanged(app, struct('Source', app.gpsMode))

        end

        % Value changed function: FindPeaks_Type
        function FindPeaksDropDownValueChanged(app, event)
            
            idx1 = app.Tree.SelectedNodes.NodeData;
            idx2 = app.Tree.SelectedNodes.UserData;

            switch app.FindPeaks_Type.Value
                case 'Valores padrão (appColeta)'
                    set(findobj(app.FindPeaks_Grid, 'Type', 'uispinner'), Enable=0)

                    app.FindPeaks_nSweeps.Value    = class.Constants.FindPeaks.nSweeps;
                    app.FindPeaks_Prominence.Value = class.Constants.FindPeaks.Prominence;
                    app.FindPeaks_Distance.Value   = class.Constants.FindPeaks.Distance;
                    app.FindPeaks_BW.Value         = class.Constants.FindPeaks.BW;

                    if app.ModeButtonEdit.Value
                        app.editedList(idx1).Band(idx2).MaskTrigger.FindPeaks = [];
                    end

                case 'Valores customizados'
                    FindPeaksPanel_editable(app)
                    if isempty(app.editedList(idx1).Band(idx2).MaskTrigger.FindPeaks)
                        app.editedList(idx1).Band(idx2).MaskTrigger.FindPeaks = struct('nSweeps',    class.Constants.FindPeaks.nSweeps,    ...
                                                                                       'Prominence', class.Constants.FindPeaks.Prominence, ...
                                                                                       'Distance',   class.Constants.FindPeaks.Distance,   ...
                                                                                       'BW',         class.Constants.FindPeaks.BW);
                    end

                    app.FindPeaks_nSweeps.Value    = app.editedList(idx1).Band(idx2).MaskTrigger.FindPeaks.nSweeps;
                    app.FindPeaks_Prominence.Value = app.editedList(idx1).Band(idx2).MaskTrigger.FindPeaks.Prominence;
                    app.FindPeaks_Distance.Value   = app.editedList(idx1).Band(idx2).MaskTrigger.FindPeaks.Distance;
                    app.FindPeaks_BW.Value         = app.editedList(idx1).Band(idx2).MaskTrigger.FindPeaks.BW;

                    if app.ModeButtonEdit.Value
                        app.editedList(idx1).Band(idx2).MaskTrigger.FindPeaks = struct('nSweeps',    app.FindPeaks_nSweeps.Value,    ...
                                                                                       'Prominence', app.FindPeaks_Prominence.Value, ...
                                                                                       'Distance',   app.FindPeaks_Distance.Value,   ...
                                                                                       'BW',         app.FindPeaks_BW.Value);
                    end
            end
            
        end

        % Selection changed function: ModePanel
        function OperationModeValueChanged(app, event)
            
            %-------------------------------------------------------------%
            % ## MODO DE VISUALIZAÇÃO ##
            %-------------------------------------------------------------%
            if app.ModeButtonView.Value
                % Aspectos relacionados à indicação visual de que se trata 
                % do modo de visualização:
                set(findobj(app.TreeGrid, 'Type', 'uiimage'), 'Enable', 'off')
                app.TreeGrid.ColumnWidth{end} = 0;                
                app.ConfirmEditionButton.Visible  = 0;
                app.ImportButton.Enable   = 'on';
                app.ExportButton.Enable = 'on';

                % Desabilita edição do conteúdo dos campos...
                app.Name.Editable            = 'off';
                app.Duration.Editable        = 'off';
                set(findobj(app.GPS_Grid,              'Type', 'uinumericeditfield', '-or', 'Type', 'uieditfield'), Editable='off')
                set(findobj(app.BandSpecificInfo_Grid, 'Type', 'uinumericeditfield', '-or', 'Type', 'uieditfield'), Editable='off')

                set(app.BitsPerPoint,    'Items', {app.BitsPerPoint.Value})
                set(app.ObservationType, 'Items', {app.ObservationType.Value})
                set(app.DurationUnit,    'Items', {app.DurationUnit.Value})
                set(app.gpsMode,         'Items', {app.gpsMode.Value})
                set(app.Status,          'Items', {app.Status.Value})
                set(app.MaskTrigger,     'Items', {app.MaskTrigger.Value})
                set(app.RFMode,          'Items', {app.RFMode.Value})
                set(app.TraceMode,       'Items', {app.TraceMode.Value})
                set(app.Detector,        'Items', {app.Detector.Value})
                set(app.LevelUnit,       'Items', {app.LevelUnit.Value})
                set(app.VBW,             'Items', {app.VBW.Value})
                set(app.FindPeaks_Type,  'Items', {app.FindPeaks_Type.Value})

                % Essa última validação é essencial para desfazer alterações 
                % que não foram salvas. Ou seja, o usuário fez alterações
                % em app.taskList (que estavam armazenadas na sua cópia -
                % app.editedList) e não clicou no botão "Confirma edição".
                if ~isequal(app.taskList, app.editedList)
                    app.editedList = app.taskList;
                    TreeBuilding(app, [])
                end

            %-------------------------------------------------------------%
            % ## MODO DE EDIÇÃO ##
            %-------------------------------------------------------------%
            else
                % Aspectos relacionados à indicação visual de que se trata 
                % do modo de edição:
                set(app.TreeGrid.Children, 'Enable', 'on')
                app.TreeGrid.ColumnWidth{end} = 16;                
                app.ConfirmEditionButton.Visible  = 1;
                app.ImportButton.Enable   = 'off';
                app.ExportButton.Enable = 'off';

                % Habilita edição do conteúdo dos campos...

                app.Name.Editable            = 'on';
                app.Duration.Editable        = 'on';
                set(findobj(app.GPS_Grid,              'Type', 'uinumericeditfield', '-or', 'Type', 'uieditfield'), Editable='on')
                set(findobj(app.BandSpecificInfo_Grid, 'Type', 'uinumericeditfield', '-or', 'Type', 'uieditfield'), Editable='on')
                app.ID.Editable              = 'off';

                set(app.BitsPerPoint,    'Items', {'8 bits', '16 bits', '32 bits'})
                set(app.ObservationType, 'Items', {'Duração', 'Período específico', 'Quantidade específica de amostras'})
                set(app.DurationUnit,    'Items', {'min', 'hr'})
                set(app.gpsMode,         'Items', {'auto', 'manual'})
                set(app.Status,          'Items', {'ON', 'OFF'})
                set(app.MaskTrigger,     'Items', {'OFF', 'ON - Apenas afere rompimento', 'ON - Afere rompimento e salva em arquivo (caso rompida máscara)', 'ON - Afere rompimento e salva em arquivo'})
                set(app.RFMode,          'Items', {'High Sensitivity', 'Normal', 'Low Distortion'})
                set(app.TraceMode,       'Items', {'ClearWrite', 'Average', 'MaxHold', 'MinHold'})
                set(app.Detector,        'Items', {'Sample', 'Average/RMS', 'Positive Peak', 'Negative Peak'})
                set(app.LevelUnit,       'Items', {'dBm', 'dBµV'})
                set(app.VBW,             'Items', {'auto', 'RBW', 'RBW/10', 'RBW/100'})
                set(app.FindPeaks_Type,  'Items', {'Valores padrão (appColeta)', 'Valores customizados'})
            end


            if strcmp(app.ObservationType.Value, 'Período específico')
                SpecificTimePanel_editable(app)
            end


            if strcmp(app.FindPeaks_Type.Value, 'Valores customizados')
                FindPeaksPanel_editable(app)
            end

        end

        % Image clicked function: ImportButton
        function ImportButtonPushed(app, event)
            
            [File, Folder] = uigetfile({'*.json', '*.json'}, 'Selecione um arquivo', 'MultiSelect', 'off');
            figure(app.UIFigure)

            if File
            [tempList, msgError] =  util.TaskScriptIO.readScriptFile(fullfile(Folder, File), 'auxApp.winEditTaskList');

                if isempty(msgError)
                    app.taskList   = [app.taskList; tempList];
                    app.editedList = app.taskList;
                    update(app)

                    TreeBuilding(app, [])
                else
                    ui.Dialog(app.UIFigure, "error", msgError);
                end
            end

        end

        % Image clicked function: ExportButton
        function ExportButtonPushed(app, event)
            
            Folder = uigetdir(app.mainApp.General.fileFolder.userPath, 'Escolha o diretório em que será salva a lista de tarefas');
            figure(app.UIFigure)

            if Folder
                saveNewFile(app, Folder, true)
            end
            
        end

        % Image clicked function: TreeAddTaskNode
        function TreeAddTaskNodePushed(app, event)
            
            idx1_old = app.Tree.SelectedNodes.NodeData;
            idx1_new = numel(app.editedList) + 1;

            idx2 = 1;

            app.editedList(idx1_new) = app.editedList(idx1_old);
            app.editedList(idx1_new).Name = sprintf('%s (Cópia)', app.editedList(idx1_old).Name);
            
            TreeBuilding(app, [idx1_new, idx2])

        end

        % Image clicked function: TreeAddBandNode
        function TreeAddBandNodeValueChanged(app, event)
            
            idx1 = app.Tree.SelectedNodes.NodeData;
            
            idx2_old = app.Tree.SelectedNodes.UserData;
            idx2_new = numel(app.editedList(idx1).Band) + 1;

            if app.Tree.SelectedNodes.Parent == app.Tree
                app.editedList(idx1).Band(idx2_new) = app.editedList(idx1).Band(1);
            else
                app.editedList(idx1).Band(idx2_new) = app.editedList(idx1).Band(idx2_old);
            end
             app.editedList(idx1).Band(idx2_new).ID = idx2_new;
            
            TreeBuilding(app, [idx1, idx2_new])

        end

        % Image clicked function: TreeDelNode
        function TreeDelNodePushed(app, event)
            
            idx1 = app.Tree.SelectedNodes.NodeData;
            idx2 = app.Tree.SelectedNodes.UserData;
            
            if app.Tree.SelectedNodes.Parent == app.Tree
                if numel(app.editedList) > 1
                    app.editedList(idx1) = [];
                    TreeBuilding(app, [1, -1])

                else
                    ui.Dialog(app.UIFigure, "warning", 'Não é possível excluir a única tarefa.');
                    return
                end

            else
                if numel(app.editedList(idx1).Band) > 1
                    app.editedList(idx1).Band(idx2) = [];
                    newID(app)
                    TreeBuilding(app, [idx1, 1])

                else
                    ui.Dialog(app.UIFigure, "warning", 'Não é possível excluir a única faixa de frequência da tarefa.');
                    return
                end
            end            

        end

        % Button pushed function: ConfirmEditionButton
        function ConfirmEditionButtonPushed(app, event)
            
            % Finalizada a edição, avalia-se se algum parâmetro foi, de fato, 
            % alterado, salvando uma nova versão do arquivo "TaskList.json",
            % caso necessário.

            if ~isequal(app.taskList, app.editedList)
                % Validaçao dos valores das faixas - os outros campos já são 
                % validados pelos próprios componentes da interface.                
                for ii = 1:numel(app.editedList)
                    for jj = 1:numel(app.editedList(ii).Band)
                        freqStart = app.editedList(ii).Band.FreqStart;
                        freqStop  = app.editedList(ii).Band.FreqStop;

                        if freqStart >= freqStop
                            ui.Dialog(app.UIFigure, "warning", sprintf('A faixa <b>%.3f - %.3f MHz</b>, da tarefa "%s", é inválida. A frequência final de uma faixa deve ser superior à inicial.', freqStart/1e+6, freqStop/1e+6, app.editedList(ii).Name));
                            return
                        end
                    end
                end

                app.taskList = app.editedList;
                update(app)
            end
            
            app.ModeButtonView.Value = 1;
            OperationModeValueChanged(app)

        end

        % Value changed function: BitsPerPoint, Description, Detector, 
        % ...and 29 other components
        function TaskParameterChanged(app, event)
            
            idx1 = app.Tree.SelectedNodes.NodeData;
            idx2 = app.Tree.SelectedNodes.UserData;

            switch event.Source
                %---------------------------------------------------------%
                % Painel "ASPECTOS GERAIS"
                %---------------------------------------------------------%
                case app.Name
                    app.editedList(idx1).Name    = app.Name.Value;
                    app.Tree.Children(idx1).Text = app.Name.Value;

                case app.BitsPerPoint
                    app.editedList(idx1).BitsPerSample = str2double(extractBefore(app.BitsPerPoint.Value, 'bits'));
                
                case {app.Duration, app.DurationUnit}
                    app.editedList(idx1).Observation.Type = 'Duration';
                    switch app.DurationUnit.Value
                        case 'min'; app.editedList(idx1).Observation.Duration = app.Duration.Value * 60;
                        case 'hr';  app.editedList(idx1).Observation.Duration = app.Duration.Value * 3600;
                    end

                case {app.SpecificTime_DatePicker1, app.SpecificTime_Spinner1, app.SpecificTime_Spinner2, app.SpecificTime_DatePicker2, app.SpecificTime_Spinner3, app.SpecificTime_Spinner4}
                    app.editedList(idx1).Observation.Type = 'Time';

                    BeginTime = app.SpecificTime_DatePicker1.Value + hours(app.SpecificTime_Spinner1.Value) + minutes(app.SpecificTime_Spinner2.Value);
                    EndTime   = app.SpecificTime_DatePicker2.Value + hours(app.SpecificTime_Spinner3.Value) + minutes(app.SpecificTime_Spinner4.Value);

                    app.editedList(idx1).Observation.BeginTime = datestr(BeginTime, 'dd/mm/yyyy HH:MM:ss');
                    app.editedList(idx1).Observation.EndTime   = datestr(EndTime,   'dd/mm/yyyy HH:MM:ss');

                case {app.gpsMode, app.GPS_manualLatitude, app.GPS_manualLongitude, app.GPS_RevisitTime}
                    switch app.gpsMode.Value
                        case 'auto'
                            app.editedList(idx1).GPS = struct('Type',        'auto', ...
                                                              'Latitude',    [],     ...
                                                              'Longitude',   [],     ...
                                                              'RevisitTime', app.GPS_RevisitTime.Value);
                        case 'manual'
                            app.editedList(idx1).GPS = struct('Type',        'manual',                      ...
                                                              'Latitude',    app.GPS_manualLatitude.Value,  ...
                                                              'Longitude',   app.GPS_manualLongitude.Value, ...
                                                              'RevisitTime', app.GPS_RevisitTime.Value);
                    end

                %---------------------------------------------------------%                
                % Painel "ESPECIFICIDADES DO FLUXO SELECIONADO"
                %---------------------------------------------------------%
                case app.Status
                    if isscalar(app.editedList(idx1).Band)
                        app.Status.Value = "ON";
                        ui.Dialog(app.UIFigure, "warning", 'Tarefa com apenas uma única faixa de frequência não pode ter essa faixa com o estado "OFF".');
                        return

                    else
                        if app.Status.Value == "ON"; app.editedList(idx1).Band(idx2).Enable = 1;
                        else;                        app.editedList(idx1).Band(idx2).Enable = 0;
                        end

                        if all(~[app.editedList(idx1).Band.Enable])
                            app.editedList(idx1).Band(idx2).Enable = 1;

                            app.Status.Value = "ON";
                            ui.Dialog(app.UIFigure, "warning", 'Toda tarefa deve possuir ao menos uma faixa de frequência com o estado "ON".');
                            return
                        end
    
                        TreeBuilding_addStyle(app)
                    end

                %---------------------------------------------------------%
                case app.MaskTrigger
                    switch app.MaskTrigger.Value
                        case 'OFF';                                                             app.editedList(idx1).Band(idx2).MaskTrigger.Status = 0;
                        case 'ON - Apenas afere rompimento';                                    app.editedList(idx1).Band(idx2).MaskTrigger.Status = 1;
                        case 'ON - Afere rompimento e salva em arquivo (caso rompida máscara)'; app.editedList(idx1).Band(idx2).MaskTrigger.Status = 2;
                        case 'ON - Afere rompimento e salva em arquivo';                        app.editedList(idx1).Band(idx2).MaskTrigger.Status = 3;
                    end

                %---------------------------------------------------------%
                case app.Description
                    app.editedList(idx1).Band(idx2).Description = app.Description.Value;

                %---------------------------------------------------------%
                case app.ObservationSamples
                    app.editedList(idx1).Observation.Type = 'Samples';
                    if isscalar(idx2)
                        app.editedList(idx1).Band(idx2).ObservationSamples = app.ObservationSamples.Value;
                    end

                %---------------------------------------------------------%
                case app.FreqStart
                    app.editedList(idx1).Band(idx2).FreqStart   = app.FreqStart.Value * 1e+6;
                    app.Tree.Children(idx1).Children(idx2).Text = TreeBuilding_nodeText(app, idx1, idx2);
                    SpanCheck(app)
                    
                %---------------------------------------------------------%
                case app.FreqStop
                    app.editedList(idx1).Band(idx2).FreqStop    = app.FreqStop.Value * 1e+6;
                    app.Tree.Children(idx1).Children(idx2).Text = TreeBuilding_nodeText(app, idx1, idx2);
                    SpanCheck(app)

                %---------------------------------------------------------%
                case app.StepWidth
                    app.editedList(idx1).Band(idx2).StepWidth = app.StepWidth.Value * 1e+3;

                %---------------------------------------------------------%
                case app.Resolution
                    app.editedList(idx1).Band(idx2).Resolution = app.Resolution.Value * 1e+3;

                %---------------------------------------------------------%
                case app.TraceMode
                    app.editedList(idx1).Band(idx2).TraceMode = app.TraceMode.Value;
                    IntegrationFactorCheck(app)
                    app.editedList(idx1).Band(idx2).IntegrationFactor = app.IntegrationFactor.Value;

                %---------------------------------------------------------%
                case app.IntegrationFactor
                    app.editedList(idx1).Band(idx2).IntegrationFactor = app.IntegrationFactor.Value;

                %---------------------------------------------------------%
                case app.RFMode
                    app.editedList(idx1).Band(idx2).RFMode = app.RFMode.Value;

                %---------------------------------------------------------%
                case app.VBW
                    app.editedList(idx1).Band(idx2).VBW = app.VBW.Value;

                %---------------------------------------------------------%
                case app.Detector
                    app.editedList(idx1).Band(idx2).Detector = app.Detector.Value;

                %---------------------------------------------------------%
                case app.LevelUnit
                    app.editedList(idx1).Band(idx2).LevelUnit = app.LevelUnit.Value;

                %---------------------------------------------------------%
                case app.RevisitTime
                    app.editedList(idx1).Band(idx2).RevisitTime = app.RevisitTime.Value;

                %---------------------------------------------------------%
                % Subpainel "FINDPEAKS"
                %---------------------------------------------------------%
                case app.FindPeaks_nSweeps
                    app.editedList(idx1).Band(idx2).MaskTrigger.FindPeaks.nSweeps    = app.FindPeaks_nSweeps.Value;

                %---------------------------------------------------------%
                case app.FindPeaks_Prominence
                    app.editedList(idx1).Band(idx2).MaskTrigger.FindPeaks.Prominence = app.FindPeaks_Prominence.Value;

                %---------------------------------------------------------%
                case app.FindPeaks_Distance
                    app.editedList(idx1).Band(idx2).MaskTrigger.FindPeaks.Distance   = app.FindPeaks_Distance.Value;

                %---------------------------------------------------------%
                case app.FindPeaks_BW
                    app.editedList(idx1).Band(idx2).MaskTrigger.FindPeaks.BW         = app.FindPeaks_BW.Value;
            end
            
        end

        % Image clicked function: TreeMoveDown, TreeMoveUp
        function UpDownImageClicked(app, event)
            
            idx1 = app.Tree.SelectedNodes.NodeData;
            idx2 = app.Tree.SelectedNodes.UserData;

            Flag = 0;

            switch event.Source
                case app.TreeMoveUp
                    if app.Tree.SelectedNodes.Parent == app.Tree
                        if idx1 > 1
                            app.editedList(idx1-1:idx1) = flip(app.editedList(idx1-1:idx1));

                            Flag = 1;
                            idx1 = idx1-1;
                        end
                    else
                        if idx2 > 1
                            app.editedList(idx1).Band(idx2-1:idx2) = flip(app.editedList(idx1).Band(idx2-1:idx2));
                            newID(app)

                            Flag = 1;
                            idx2 = idx2-1;
                        end
                    end

                case app.TreeMoveDown
                    if app.Tree.SelectedNodes.Parent == app.Tree
                        if idx1 < numel(app.editedList)
                            app.editedList(idx1:idx1+1) = flip(app.editedList(idx1:idx1+1));

                            Flag = 1;
                            idx1 = idx1+1;
                        end
                    else
                        if idx2 < numel(app.editedList(idx1).Band)
                            app.editedList(idx1).Band(idx2:idx2+1) = flip(app.editedList(idx1).Band(idx2:idx2+1));
                            newID(app)

                            Flag = 1;
                            idx2 = idx2+1;
                        end
                    end
            end

            if Flag
                if app.Tree.SelectedNodes.Parent == app.Tree
                    TreeBuilding(app, [idx1, -1])
                else
                    TreeBuilding(app, [idx1, idx2])
                end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta';
            app.UIFigure.Icon = 'icon_32.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Toolbar = uigridlayout(app.GridLayout);
            app.ImportButton = uiimage(app.Toolbar);
            app.ImportButton.ImageClickedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Tooltip = {''};
            app.ImportButton.ImageSource = 'Import_16.png';
            app.ExportButton = uiimage(app.Toolbar);
            app.ExportButton.ImageClickedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Tooltip = {''};
            app.ExportButton.ImageSource = 'Export_16.png';
            app.ConfirmEditionButton = uibutton(app.Toolbar, 'push');
            app.ConfirmEditionButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmEditionButtonPushed, true);
            app.ConfirmEditionButton.Icon = 'save-16px-white.svg';
            app.ConfirmEditionButton.Visible = 'off';
            app.ConfirmEditionButton.Text = 'Salva alterações';
            app.SubTabGroup = uitabgroup(app.GridLayout);
            app.SubTab1 = uitab(app.SubTabGroup);
            app.SubTab1.Title = 'LISTA DE TAREFAS';
            app.SubGrid1 = uigridlayout(app.SubTab1);
            app.ModePanelLabel = uilabel(app.SubGrid1);
            app.ModePanelLabel.Text = 'MODO';
            app.ModePanel = uibuttongroup(app.SubGrid1);
            app.ModePanel.SelectionChangedFcn = createCallbackFcn(app, @OperationModeValueChanged, true);
            app.ModeButtonView = uiradiobutton(app.ModePanel);
            app.ModeButtonView.Text = '<font style="color:#0000ff;">VISUALIZAR</font> lista';
            app.ModeButtonView.Interpreter = 'html';
            app.ModeButtonView.Value = true;
            app.ModeButtonEdit = uiradiobutton(app.ModePanel);
            app.ModeButtonEdit.Text = '<font style="color:#a2142f;"><b>EDITAR</b></font> lista';
            app.ModeButtonEdit.Interpreter = 'html';
            app.TreeGrid = uigridlayout(app.SubGrid1);
            app.Tree = uitree(app.TreeGrid);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.TreeAddTaskNode = uiimage(app.TreeGrid);
            app.TreeAddTaskNode.ImageClickedFcn = createCallbackFcn(app, @TreeAddTaskNodePushed, true);
            app.TreeAddTaskNode.Enable = 'off';
            app.TreeAddTaskNode.Tooltip = {''};
            app.TreeAddTaskNode.ImageSource = 'addFileWithPlus_32.png';
            app.TreeAddBandNode = uiimage(app.TreeGrid);
            app.TreeAddBandNode.ImageClickedFcn = createCallbackFcn(app, @TreeAddBandNodeValueChanged, true);
            app.TreeAddBandNode.Enable = 'off';
            app.TreeAddBandNode.Tooltip = {''};
            app.TreeAddBandNode.ImageSource = 'EditWithPlus_32.png';
            app.TreeDelNode = uiimage(app.TreeGrid);
            app.TreeDelNode.ImageClickedFcn = createCallbackFcn(app, @TreeDelNodePushed, true);
            app.TreeDelNode.Enable = 'off';
            app.TreeDelNode.Tooltip = {''};
            app.TreeDelNode.ImageSource = 'Delete_32Red.png';
            app.TreeMoveUp = uiimage(app.TreeGrid);
            app.TreeMoveUp.ImageClickedFcn = createCallbackFcn(app, @UpDownImageClicked, true);
            app.TreeMoveUp.Enable = 'off';
            app.TreeMoveUp.Tooltip = {''};
            app.TreeMoveUp.ImageSource = 'ArrowUp_32.png';
            app.TreeMoveDown = uiimage(app.TreeGrid);
            app.TreeMoveDown.ImageClickedFcn = createCallbackFcn(app, @UpDownImageClicked, true);
            app.TreeMoveDown.Enable = 'off';
            app.TreeMoveDown.Tooltip = {''};
            app.TreeMoveDown.ImageSource = 'ArrowDown_32.png';
            app.TreeLabel = uilabel(app.SubGrid1);
            app.TreeLabel.Text = 'TAREFAS';
            app.PanelGrid = uigridlayout(app.SubGrid1);
            app.Tab2_PanelGrid = uigridlayout(app.PanelGrid);
            app.NameLabel = uilabel(app.Tab2_PanelGrid);
            app.NameLabel.Text = 'NOME';
            app.Name = uieditfield(app.Tab2_PanelGrid, 'text');
            app.Name.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.Name.Editable = 'off';
            app.BitsPerPointLabel = uilabel(app.Tab2_PanelGrid);
            app.BitsPerPointLabel.Text = 'CODIFICAÇÃO';
            app.BitsPerPoint = uidropdown(app.Tab2_PanelGrid);
            app.BitsPerPoint.Items = {'8 bits', '16 bits', '32 bits'};
            app.BitsPerPoint.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.BitsPerPoint.Value = '8 bits';
            app.ObservationLabel = uilabel(app.Tab2_PanelGrid);
            app.ObservationLabel.Text = 'PERÍODO DE OBSERVAÇÃO';
            app.ObservationPanel = uipanel(app.Tab2_PanelGrid);
            app.ObservationPanel_Grid = uigridlayout(app.ObservationPanel);
            app.ObservationTypeLabel = uilabel(app.ObservationPanel_Grid);
            app.ObservationTypeLabel.Text = 'Critério de término:';
            app.ObservationType = uidropdown(app.ObservationPanel_Grid);
            app.ObservationType.Items = {'Duração', 'Período específico', 'Quantidade específica de amostras'};
            app.ObservationType.ValueChangedFcn = createCallbackFcn(app, @ObservationTimeValueChanged, true);
            app.ObservationType.Tag = 'task_Editable';
            app.ObservationType.Value = 'Duração';
            app.Duration_Grid = uigridlayout(app.ObservationPanel_Grid);
            app.DurationUnit = uidropdown(app.Duration_Grid);
            app.DurationUnit.Items = {'min', 'hr'};
            app.DurationUnit.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.DurationUnit.Tag = 'task_Editable';
            app.DurationUnit.Value = 'min';
            app.Duration = uieditfield(app.Duration_Grid, 'numeric');
            app.Duration.Limits = [1 Inf];
            app.Duration.ValueDisplayFormat = '%.3f';
            app.Duration.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.Duration.Tag = 'task_Editable';
            app.Duration.Editable = 'off';
            app.Duration.Value = 10;
            app.SpecificTime_Grid = uigridlayout(app.ObservationPanel_Grid);
            app.SpecificTime_DatePicker1 = uidatepicker(app.SpecificTime_Grid);
            app.SpecificTime_DatePicker1.DisplayFormat = 'dd/MM/uuuu';
            app.SpecificTime_DatePicker1.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.SpecificTime_DatePicker1.Enable = 'off';
            app.SpecificTime_DatePicker1.Visible = 'off';
            app.SpecificTime_Spinner1 = uispinner(app.SpecificTime_Grid);
            app.SpecificTime_Spinner1.Limits = [0 23];
            app.SpecificTime_Spinner1.RoundFractionalValues = 'on';
            app.SpecificTime_Spinner1.ValueDisplayFormat = '%.0f';
            app.SpecificTime_Spinner1.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.SpecificTime_Spinner1.Enable = 'off';
            app.SpecificTime_Spinner1.Visible = 'off';
            app.SpecificTime_Spinner2 = uispinner(app.SpecificTime_Grid);
            app.SpecificTime_Spinner2.Step = 10;
            app.SpecificTime_Spinner2.Limits = [0 59];
            app.SpecificTime_Spinner2.RoundFractionalValues = 'on';
            app.SpecificTime_Spinner2.ValueDisplayFormat = '%.0f';
            app.SpecificTime_Spinner2.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.SpecificTime_Spinner2.Enable = 'off';
            app.SpecificTime_Spinner2.Visible = 'off';
            app.SpecificTime_DatePicker2 = uidatepicker(app.SpecificTime_Grid);
            app.SpecificTime_DatePicker2.DisplayFormat = 'dd/MM/uuuu';
            app.SpecificTime_DatePicker2.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.SpecificTime_DatePicker2.Enable = 'off';
            app.SpecificTime_DatePicker2.Visible = 'off';
            app.SpecificTime_Spinner3 = uispinner(app.SpecificTime_Grid);
            app.SpecificTime_Spinner3.Limits = [0 23];
            app.SpecificTime_Spinner3.RoundFractionalValues = 'on';
            app.SpecificTime_Spinner3.ValueDisplayFormat = '%.0f';
            app.SpecificTime_Spinner3.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.SpecificTime_Spinner3.Enable = 'off';
            app.SpecificTime_Spinner3.Visible = 'off';
            app.SpecificTime_Spinner3.Value = 23;
            app.SpecificTime_Spinner4 = uispinner(app.SpecificTime_Grid);
            app.SpecificTime_Spinner4.Step = 10;
            app.SpecificTime_Spinner4.Limits = [0 59];
            app.SpecificTime_Spinner4.RoundFractionalValues = 'on';
            app.SpecificTime_Spinner4.ValueDisplayFormat = '%.0f';
            app.SpecificTime_Spinner4.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.SpecificTime_Spinner4.Enable = 'off';
            app.SpecificTime_Spinner4.Visible = 'off';
            app.SpecificTime_Spinner4.Value = 59;
            app.SpecificTime_Mark1 = uilabel(app.SpecificTime_Grid);
            app.SpecificTime_Mark1.Enable = 'off';
            app.SpecificTime_Mark1.Visible = 'off';
            app.SpecificTime_Mark1.Text = ':';
            app.SpecificTime_Mark2 = uilabel(app.SpecificTime_Grid);
            app.SpecificTime_Mark2.Enable = 'off';
            app.SpecificTime_Mark2.Visible = 'off';
            app.SpecificTime_Mark2.Text = ':';
            app.gpsModeLabel = uilabel(app.Tab2_PanelGrid);
            app.gpsModeLabel.Text = 'GPS';
            app.gpsMode = uidropdown(app.Tab2_PanelGrid);
            app.gpsMode.Items = {'auto', 'manual'};
            app.gpsMode.ValueChangedFcn = createCallbackFcn(app, @gpsModeValueChanged, true);
            app.gpsMode.Tag = 'task_Editable';
            app.gpsMode.Value = 'auto';
            app.GPS_Panel = uipanel(app.Tab2_PanelGrid);
            app.GPS_Grid = uigridlayout(app.GPS_Panel);
            app.GPS_manualLatitudeLabel = uilabel(app.GPS_Grid);
            app.GPS_manualLatitudeLabel.Text = 'Latitude (º):';
            app.GPS_manualLatitude = uieditfield(app.GPS_Grid, 'numeric');
            app.GPS_manualLatitude.ValueDisplayFormat = '%.6f';
            app.GPS_manualLatitude.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.GPS_manualLatitude.Tag = 'task_Editable';
            app.GPS_manualLatitude.Editable = 'off';
            app.GPS_manualLatitude.Enable = 'off';
            app.GPS_manualLatitude.Value = -1;
            app.GPS_manualLongitudeLabel = uilabel(app.GPS_Grid);
            app.GPS_manualLongitudeLabel.Text = 'Longitude (º):';
            app.GPS_manualLongitude = uieditfield(app.GPS_Grid, 'numeric');
            app.GPS_manualLongitude.ValueDisplayFormat = '%.6f';
            app.GPS_manualLongitude.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.GPS_manualLongitude.Tag = 'task_Editable';
            app.GPS_manualLongitude.Editable = 'off';
            app.GPS_manualLongitude.Enable = 'off';
            app.GPS_manualLongitude.Value = -1;
            app.GPS_RevisitTimeLabel = uilabel(app.GPS_Grid);
            app.GPS_RevisitTimeLabel.Text = 'Revisita (seg):';
            app.GPS_RevisitTime = uieditfield(app.GPS_Grid, 'numeric');
            app.GPS_RevisitTime.Limits = [1 Inf];
            app.GPS_RevisitTime.RoundFractionalValues = 'on';
            app.GPS_RevisitTime.ValueDisplayFormat = '%.0f';
            app.GPS_RevisitTime.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.GPS_RevisitTime.Tag = 'task_Editable';
            app.GPS_RevisitTime.Editable = 'off';
            app.GPS_RevisitTime.Value = 60;
            app.BandSpecificInfo_Grid = uigridlayout(app.PanelGrid);
            app.StatusLabel = uilabel(app.BandSpecificInfo_Grid);
            app.StatusLabel.Text = 'ESTADO';
            app.Status = uidropdown(app.BandSpecificInfo_Grid);
            app.Status.Items = {'ON', 'OFF'};
            app.Status.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.Status.Enable = 'off';
            app.Status.Value = 'ON';
            app.MaskTriggerLabel = uilabel(app.BandSpecificInfo_Grid);
            app.MaskTriggerLabel.Text = 'MÁSCARA ESPECTRAL';
            app.MaskTrigger = uidropdown(app.BandSpecificInfo_Grid);
            app.MaskTrigger.Items = {'OFF', 'ON - Apenas afere rompimento', 'ON - Afere rompimento e salva em arquivo (caso rompida máscara)', 'ON - Afere rompimento e salva em arquivo'};
            app.MaskTrigger.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.MaskTrigger.Enable = 'off';
            app.MaskTrigger.Value = 'ON - Afere rompimento e salva em arquivo (caso rompida máscara)';
            app.IDLabel = uilabel(app.BandSpecificInfo_Grid);
            app.IDLabel.Text = 'ID';
            app.ID = uieditfield(app.BandSpecificInfo_Grid, 'numeric');
            app.ID.Limits = [1 Inf];
            app.ID.RoundFractionalValues = 'on';
            app.ID.ValueDisplayFormat = '%.0f';
            app.ID.Editable = 'off';
            app.ID.Enable = 'off';
            app.ID.Value = 1;
            app.DescriptionLabel = uilabel(app.BandSpecificInfo_Grid);
            app.DescriptionLabel.Text = 'DESCRIÇÃO';
            app.Description = uieditfield(app.BandSpecificInfo_Grid, 'text');
            app.Description.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.Description.Editable = 'off';
            app.Description.Enable = 'off';
            app.ObservationSamplesLabel = uilabel(app.BandSpecificInfo_Grid);
            app.ObservationSamplesLabel.Text = 'AMOSTRAS A COLETAR';
            app.ObservationSamples = uieditfield(app.BandSpecificInfo_Grid, 'numeric');
            app.ObservationSamples.Limits = [-1 Inf];
            app.ObservationSamples.RoundFractionalValues = 'on';
            app.ObservationSamples.ValueDisplayFormat = '%.0f';
            app.ObservationSamples.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.ObservationSamples.Editable = 'off';
            app.ObservationSamples.Enable = 'off';
            app.ObservationSamples.Value = -1;
            app.FreqStartLabel = uilabel(app.BandSpecificInfo_Grid);
            app.FreqStartLabel.Text = 'FREQ. INICIAL (MHz)';
            app.FreqStart = uieditfield(app.BandSpecificInfo_Grid, 'numeric');
            app.FreqStart.Limits = [0.1 100000];
            app.FreqStart.ValueDisplayFormat = '%.6f';
            app.FreqStart.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.FreqStart.Editable = 'off';
            app.FreqStart.Enable = 'off';
            app.FreqStart.Value = 108;
            app.FreqStopLabel = uilabel(app.BandSpecificInfo_Grid);
            app.FreqStopLabel.Text = 'FREQ. FINAL (MHz)';
            app.FreqStop = uieditfield(app.BandSpecificInfo_Grid, 'numeric');
            app.FreqStop.Limits = [0.1 100000];
            app.FreqStop.ValueDisplayFormat = '%.6f';
            app.FreqStop.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.FreqStop.Editable = 'off';
            app.FreqStop.Enable = 'off';
            app.FreqStop.Value = 108;
            app.StepWidthLabel = uilabel(app.BandSpecificInfo_Grid);
            app.StepWidthLabel.Text = 'PASSO (kHz)';
            app.StepWidth = uieditfield(app.BandSpecificInfo_Grid, 'numeric');
            app.StepWidth.Limits = [1 Inf];
            app.StepWidth.ValueDisplayFormat = '%.3f';
            app.StepWidth.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.StepWidth.Editable = 'off';
            app.StepWidth.Enable = 'off';
            app.StepWidth.Value = 1;
            app.ResolutionLabel = uilabel(app.BandSpecificInfo_Grid);
            app.ResolutionLabel.Text = 'RESOLUÇÃO (kHz)';
            app.Resolution = uieditfield(app.BandSpecificInfo_Grid, 'numeric');
            app.Resolution.Limits = [1 Inf];
            app.Resolution.ValueDisplayFormat = '%.3f';
            app.Resolution.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.Resolution.Editable = 'off';
            app.Resolution.Enable = 'off';
            app.Resolution.Value = 1;
            app.TraceModeLabel = uilabel(app.BandSpecificInfo_Grid);
            app.TraceModeLabel.Text = 'TRAÇO';
            app.TraceMode = uidropdown(app.BandSpecificInfo_Grid);
            app.TraceMode.Items = {'ClearWrite', 'Average', 'MaxHold', 'MinHold'};
            app.TraceMode.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.TraceMode.Enable = 'off';
            app.TraceMode.Value = 'Average';
            app.IntegrationFactorLabel = uilabel(app.BandSpecificInfo_Grid);
            app.IntegrationFactorLabel.Text = 'FATOR INTEGRAÇÃO';
            app.IntegrationFactor = uieditfield(app.BandSpecificInfo_Grid, 'numeric');
            app.IntegrationFactor.Limits = [1 Inf];
            app.IntegrationFactor.RoundFractionalValues = 'on';
            app.IntegrationFactor.ValueDisplayFormat = '%.0f';
            app.IntegrationFactor.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.IntegrationFactor.Editable = 'off';
            app.IntegrationFactor.Enable = 'off';
            app.IntegrationFactor.Value = 1;
            app.RFModeLabel = uilabel(app.BandSpecificInfo_Grid);
            app.RFModeLabel.Text = 'MODO DE RF';
            app.RFMode = uidropdown(app.BandSpecificInfo_Grid);
            app.RFMode.Items = {'High Sensitivity', 'Low Distortion', 'Normal'};
            app.RFMode.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.RFMode.Enable = 'off';
            app.RFMode.Value = 'High Sensitivity';
            app.VBWLabel = uilabel(app.BandSpecificInfo_Grid);
            app.VBWLabel.Text = 'VBW';
            app.VBW = uidropdown(app.BandSpecificInfo_Grid);
            app.VBW.Items = {'auto', 'RBW', 'RBW/10', 'RBW/100'};
            app.VBW.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.VBW.Enable = 'off';
            app.VBW.Value = 'auto';
            app.DetectorLabel = uilabel(app.BandSpecificInfo_Grid);
            app.DetectorLabel.Text = 'DETECTOR';
            app.Detector = uidropdown(app.BandSpecificInfo_Grid);
            app.Detector.Items = {'Sample', 'Average/RMS', 'Positive Peak', 'Negative Peak'};
            app.Detector.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.Detector.Enable = 'off';
            app.Detector.Value = 'Sample';
            app.LevelUnitLabel = uilabel(app.BandSpecificInfo_Grid);
            app.LevelUnitLabel.Text = 'UNIDADE';
            app.LevelUnit = uidropdown(app.BandSpecificInfo_Grid);
            app.LevelUnit.Items = {'dBm', 'dBµV'};
            app.LevelUnit.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.LevelUnit.Enable = 'off';
            app.LevelUnit.Value = 'dBm';
            app.RevisitTimeLabel = uilabel(app.BandSpecificInfo_Grid);
            app.RevisitTimeLabel.Text = 'REVISITA (seg)';
            app.RevisitTime = uieditfield(app.BandSpecificInfo_Grid, 'numeric');
            app.RevisitTime.Limits = [0.001 Inf];
            app.RevisitTime.ValueDisplayFormat = '%.3f';
            app.RevisitTime.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.RevisitTime.Editable = 'off';
            app.RevisitTime.Enable = 'off';
            app.RevisitTime.Value = 1;
            app.FindPeaks_PanelLabel = uilabel(app.BandSpecificInfo_Grid);
            app.FindPeaks_PanelLabel.Text = {'PARÂMETROS RELACIONADOS À BUSCA DE EMISSÕES'; '(caso evidenciado rompimento de máscara espectral)'};
            app.FindPeaks_Panel = uipanel(app.BandSpecificInfo_Grid);
            app.FindPeaks_Grid = uigridlayout(app.FindPeaks_Panel);
            app.FindPeaks_TypeLabel = uilabel(app.FindPeaks_Grid);
            app.FindPeaks_TypeLabel.Text = 'Tipo:';
            app.FindPeaks_Type = uidropdown(app.FindPeaks_Grid);
            app.FindPeaks_Type.Items = {'Valores padrão (appColeta)', 'Valores customizados'};
            app.FindPeaks_Type.ValueChangedFcn = createCallbackFcn(app, @FindPeaksDropDownValueChanged, true);
            app.FindPeaks_Type.Enable = 'off';
            app.FindPeaks_Type.Value = 'Valores padrão (appColeta)';
            app.FindPeaks_nSweepsLabel = uilabel(app.FindPeaks_Grid);
            app.FindPeaks_nSweepsLabel.Text = {'Quantidade de'; 'varreduras:'};
            app.FindPeaks_nSweeps = uispinner(app.FindPeaks_Grid);
            app.FindPeaks_nSweeps.Limits = [1 100];
            app.FindPeaks_nSweeps.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.FindPeaks_nSweeps.Enable = 'off';
            app.FindPeaks_nSweeps.Value = 10;
            app.FindPeaks_ProminenceLabel = uilabel(app.FindPeaks_Grid);
            app.FindPeaks_ProminenceLabel.Text = {'Proeminência '; 'mínima (dB):'};
            app.FindPeaks_Prominence = uispinner(app.FindPeaks_Grid);
            app.FindPeaks_Prominence.Step = 10;
            app.FindPeaks_Prominence.Limits = [3 50];
            app.FindPeaks_Prominence.RoundFractionalValues = 'on';
            app.FindPeaks_Prominence.ValueDisplayFormat = '%.0f';
            app.FindPeaks_Prominence.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.FindPeaks_Prominence.Enable = 'off';
            app.FindPeaks_Prominence.Value = 30;
            app.FindPeaks_DistanceLabel = uilabel(app.FindPeaks_Grid);
            app.FindPeaks_DistanceLabel.Text = {'Distância mínima '; 'entre picos (kHz):'};
            app.FindPeaks_Distance = uispinner(app.FindPeaks_Grid);
            app.FindPeaks_Distance.Step = 25;
            app.FindPeaks_Distance.Limits = [0 100000];
            app.FindPeaks_Distance.RoundFractionalValues = 'on';
            app.FindPeaks_Distance.ValueDisplayFormat = '%.0f';
            app.FindPeaks_Distance.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.FindPeaks_Distance.Enable = 'off';
            app.FindPeaks_Distance.Value = 25;
            app.FindPeaks_BWLabel = uilabel(app.FindPeaks_Grid);
            app.FindPeaks_BWLabel.Text = {'Largura mínima'; 'ocupada (kHz):'};
            app.FindPeaks_BW = uispinner(app.FindPeaks_Grid);
            app.FindPeaks_BW.Step = 10;
            app.FindPeaks_BW.Limits = [0 100000];
            app.FindPeaks_BW.RoundFractionalValues = 'on';
            app.FindPeaks_BW.ValueDisplayFormat = '%.0f';
            app.FindPeaks_BW.ValueChangedFcn = createCallbackFcn(app, @TaskParameterChanged, true);
            app.FindPeaks_BW.Enable = 'off';
            app.FindPeaks_BW.Value = 10;
            app.DockModule = uigridlayout(app.GridLayout);
            app.DockModule.Visible = 'off';
            app.DockUndockButton = uiimage(app.DockModule);
            app.DockUndockButton.ImageClickedFcn = createCallbackFcn(app, @onDockModuleGroupButtonClicked, true);
            app.DockUndockButton.Enable = 'off';
            app.DockUndockButton.ImageSource = 'Undock_18White.png';
            app.DockCloseButton = uiimage(app.DockModule);
            app.DockCloseButton.ImageClickedFcn = createCallbackFcn(app, @onDockModuleGroupButtonClicked, true);
            app.DockCloseButton.ImageSource = 'Delete_12SVG_white.svg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winTaskList(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end